classdef OilSpillSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FunctionalityDropDownLabel      matlab.ui.control.Label
        FunctionalityDropDown           matlab.ui.control.DropDown
        InputsPanel                     matlab.ui.container.Panel
        ObservationsMLabel              matlab.ui.control.Label
        ObservationsMSpinner            matlab.ui.control.Spinner
        ThicknessstepmmEditFieldLabel   matlab.ui.control.Label
        ThicknessstepmmEditField        matlab.ui.control.NumericEditField
        IncidentangledegreeEditFieldLabel  matlab.ui.control.Label
        IncidentangledegreeEditField    matlab.ui.control.NumericEditField
        FrequenciesGHzEditFieldLabel    matlab.ui.control.Label
        FrequenciesGHzEditField         matlab.ui.control.EditField
        tminEditFieldLabel              matlab.ui.control.Label
        tminEditField                   matlab.ui.control.NumericEditField
        tmaxEditFieldLabel              matlab.ui.control.Label
        tmaxEditField                   matlab.ui.control.NumericEditField
        EnvironmentPropertiesPanel      matlab.ui.container.Panel
        RoughnessEditFieldLabel         matlab.ui.control.Label
        RoughnessEditField              matlab.ui.control.NumericEditField
        OildielectricEditFieldLabel     matlab.ui.control.Label
        OildielectricEditField          matlab.ui.control.NumericEditField
        AirdielectricEditFieldLabel     matlab.ui.control.Label
        AirdielectricEditField          matlab.ui.control.NumericEditField
        WaterTempertatureCEditFieldLabel  matlab.ui.control.Label
        WaterTempertatureCEditField     matlab.ui.control.NumericEditField
        WaterSalinitypptEditFieldLabel  matlab.ui.control.Label
        WaterSalinitypptEditField       matlab.ui.control.NumericEditField
        NoisePropertiesPanel            matlab.ui.container.Panel
        VarianceEditFieldLabel          matlab.ui.control.Label
        VarianceEditField               matlab.ui.control.NumericEditField
        SamplesSliderLabel              matlab.ui.control.Label
        SamplesSlider                   matlab.ui.control.Slider
        DesiredthicknessEditFieldLabel  matlab.ui.control.Label
        DesiredthicknessEditField       matlab.ui.control.NumericEditField
        SamplesDisplayEditFieldLabel    matlab.ui.control.Label
        SamplesDisplayEditField         matlab.ui.control.NumericEditField
        SpillButton                     matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        AdditionalPlotsPanel            matlab.ui.container.Panel
        OptionsDropDownLabel            matlab.ui.control.Label
        OptionsDropDown                 matlab.ui.control.DropDown
        GenerateforallfrequencytriadsButton  matlab.ui.control.Button
        GenerateforallfrequencypairsButton  matlab.ui.control.Button
        JointpdfdetectionCheckBox       matlab.ui.control.CheckBox
        DefaultdetectionCheckBox        matlab.ui.control.CheckBox
        TestingDetailsPanel             matlab.ui.container.Panel
        ThicknessEditFieldLabel         matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        ChangeDataFolderButton          matlab.ui.control.Button
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        ShowhistogramButton             matlab.ui.control.Button
        PlotsDropDownLabel              matlab.ui.control.Label
        PlotsDropDown                   matlab.ui.control.DropDown
        ClearfiguresButton              matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        ShowGridLinesCheckBox           matlab.ui.control.CheckBox
        ThicknessEstimatedLabel         matlab.ui.control.Label
        ThicknessEstimated              matlab.ui.control.NumericEditField
        ProbabilityoferrorEditFieldLabel  matlab.ui.control.Label
        ProbabilityoferrorEditField     matlab.ui.control.NumericEditField
        MaximumErrorEditFieldLabel      matlab.ui.control.Label
        MaximumErrorEditField           matlab.ui.control.NumericEditField
        mmSpinnerLabel                  matlab.ui.control.Label
        mmSpinner                       matlab.ui.control.Spinner
        Default                         matlab.ui.control.CheckBox
        Jointpdf                        matlab.ui.control.CheckBox
        ExtractFigureButton             matlab.ui.control.Button
        ExtractFigureButton_2           matlab.ui.control.Button
        ExtractFigureButton_3           matlab.ui.control.Button
        ProbabilityOfOilDetectionEditFieldLabel  matlab.ui.control.Label
        ProbabilityOfOilDetectionEditField  matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        DetectorErrorPercentageEditFieldLabel  matlab.ui.control.Label
        DetectorErrorPercentageEditField  matlab.ui.control.NumericEditField
        EstimatorErrorPercentageEditFieldLabel  matlab.ui.control.Label
        EstimatorErrorPercentageEditField  matlab.ui.control.NumericEditField
        EstimationerrormarginSpinnerLabel  matlab.ui.control.Label
        EstimationerrormarginSpinner    matlab.ui.control.Spinner
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Plots % Description
        n
        H
        G
        frequency
        ks
        E_oil
        E_air
        temp
        salinity
        theta
        M
        variance
        thickness_step
        tmin
        tmax
        Estimated_thickness
        reflectivities
        samples
        options
        functionality
        desired_thickness
        scattered_values
        Thickness_calculated
        MaximumError
        probability_of_error
        error_range
        Calculate_button_pushed
        ReflectivityLegends
        ProbabilityLegends
        Jointpdflegends
        Jointpdfdetection
        Defaultdetection
        pO
        pW
        Experimental_thickness
        Experimental_results_path
        h
        s
        Estimated_thicknesses
    end
    
    
    methods (Access = private)
        
        % Reflectivity
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function R = reflectivity(app, frequency, thickness)
            
            %  Water Dielectric constant and field reflection coefficients
            E_Water = app.E_water(frequency);
            raw12 = app.field_reflection_coefficient(sqrt(app.E_air), sqrt(app.E_oil));
            raw23  = app.field_reflection_coefficient(sqrt(app.E_oil), sqrt(E_Water));
            
            %  Phase shift & Reflectivity
            delta = (2*pi*10^9*sqrt(app.E_oil)*thickness*10^(-3))/(3*10^8);
            delta = transpose(frequency).*delta;
            
            num = raw12*exp(1i*delta) + transpose(raw23).*exp(-1i*delta);
            denum = exp(1i*delta) + (raw12 .* transpose(raw23) .* exp(-1i*delta));
            R = abs(num./denum).^2;
            
            %%    another form of reflectivity equation
            %     num = (raw12.^2 + transpose(raw23.^2)  + 2*raw12.*transpose(raw23).*cos(2.*delta));
            %     denum = 1 + transpose((raw12.*raw23).^2)+2*raw12.*transpose(raw23).*cos(2.*delta);
            %     R = num./denum;
            
            R = coherent_reflectivity(app, R);
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Coherent reflectivity according to surface roughness ks and
        % incident angle theta
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function R_coh = coherent_reflectivity(app, reflectivity)
            x = app.ks*cosd(app.theta);
            R_coh = reflectivity.*exp(-4*(x^2));
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        % Field reflection coefficient
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function r = field_reflection_coefficient(app, dielectric_constant1, dielectric_constant2)
            r = (dielectric_constant1 - dielectric_constant2)./(dielectric_constant1 + dielectric_constant2);
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        %   Water dielectric constant
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function E = E_water(app, f)
            
            %frequency is given in GHz
            t = app.temp;
            S = app.salinity;
            
            %Conductivity
            
            A = [2.903602, 8.607e-2, 4.738817e-4, -2.991e-6, 4.3041e-9];
            sig35 = A(1) + A(2)*t + A(3)*t*t + A(4)*t*t*t + A(5)*t*t*t*t;
            
            A = [37.5109, 5.45216, 0.014409, 1004.75, 182.283];
            P = S * ((A(1) + A(2)*S + A(3)*S*S) / (A(4) + A(5)*S + S*S));
            
            A = [6.9431, 3.2841, -0.099486, 84.85, 69.024];
            alpha0 = (A(1) + A(2)*S + A(3)*S*S) / (A(4) + A(5)*S + S*S);
            
            A = [49.843, -0.2276, 0.00198];
            alpha1 = A(1) + A(2)*S + A(3)*S*S;
            
            Q = 1 + ((alpha0*(t-15))/(t+alpha1));
            
            sigma = sig35*P*Q;
            
            %some used parameters
            a=[0.46606917e-2 -0.26087876e-4 -0.63926782e-5 0.63000075e1 0.26242021e-2 -0.42984155e-2 ...
                0.34414691e-4 0.17667420e-3 -0.20491560e-6 0.58366888e3 0.12634992e3 0.69227972e-4 ...
                0.38957681e-6 0.30742330e3 0.12634992e3 0.37245044e1 0.92609781e-2 -0.26093754e-1];
            
            
            epsS = 87.85306*exp(-0.00456992*t - a(1)*S - a(2)*S*S - a(3)*S*t);
            epsOne = a(4)*exp(-a(5)*t-a(6)*S-a(7)*S*t);
            tau1 = (a(8)+a(9)*S)*exp(a(10)/(t+a(11)));
            tau2 = (a(12)+a(13)*S)*exp(a(14)/(t+a(15)));
            epsInf = a(16) + a(17)*t + a(18)*S;
            
            %Complex Permitivity Calculation
            eps = ((epsS-epsOne)./(1-1i*2*pi.*f.*tau1)) + ((epsOne-epsInf)./(1-1i*2*pi.*f.*tau2)) + (epsInf) + 1i*((17.9751*sigma)./f);
            
            %Seperates real and imaginary components
            epsr = real(eps);
            epsi = imag(eps);
            
            E = epsr - epsi*1i;              % According to Microwave Radar and Radiometric Remote Sensing by David Gardner Long , Fawwaz T. Ulaby p.124
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Generates the spillage image
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function R = fractalsurface(app, n, H)
            % inputs:
            % n - number of iterations
            % H - Hurst exponent, fractal dimension D = 3-H
            % outputs:
            % row - row location of surface
            % col - column location of surface
            % R - surface matrix
            % Authors: Hui Yang
            % Affiliations:
            % The Pennsylvania State University
            % 310 Leohard Building, University Park, PA
            % Email: yanghui@gmail.com
            % If you find this toolbox useful, please cite the following papers:
            % [1]	Y. Chen and H. Yang, Numerical simulation and pattern characterization of
            % nonlinear spatiotemporal dynamics on fractal surfaces for the whole-heart modeling
            % applications, European Physical Journal, DOI: 10.1140/epjb/e2016-60960-6
            % [2]	B. Yao, F. Imani, A. Sakpal, E. W. Reutzel, and H. Yang*, �Multifractal
            % analysis of image profiles for the characterization and detection of defects
            % in additive manufacturing,� ASME Journal of Manufacturing Science and Engineering,
            % Vol. 140, No. 3, p031014-13, 2017, DOI: 10.1115/1.4037891
            % [3]	F. Imani, B. Yao, R. Chen, P. Rao, and H. Yang*, �Joint multifractal
            % and lacunarity analysis of image profiles for manufacturing quality control�.
            % ASME Journal Manufacturing Science and Engineering, Vol. 141, No. 4,
            % p 044501-7, 2019. DOI: 10.1115/1.4042579
            N=2^n; % size of surface is (N+1)*(N+1)
            L=zeros(N+1,N+1); % initial of surface
            %% first iteration
            R=randn(3,3);
            Initial = [1,2^(n-1)+1,2^n+1];
            for i=1:3
                for j=1:3
                    L(Initial(i),Initial(j))=R(i,j);
                end
            end
            [row,~]=find(L); % location of first iteration
            r=sqrt(length(row));
            %% generation of the rest iterations
            for l=1:n-1
                for i=1:r-1
                    mid(i)=(row(i)+row(i+1))/2;
                    for j=1:r
                        L(mid(i),row(j))=mean([L(row(i),row(j)),L(row(i+1),row(j))])+randn*2^(-H*(l+1));
                        L(row(j),mid(i))=mean([L(row(j),row(i)),L(row(j),row(i+1))])+randn*2^(-H*(l+1));
                    end
                end
                for i=1:r-1
                    for j=1:r-1
                        L(mid(i),mid(j))=mean([L(row(i),row(j)),L(row(i),row(j+1)),L(row(i+1),row(j)),L(row(i+1),row(j+1))])+randn*2^(-H*(l+1));
                    end
                end
                
                [row,~]=find(L);
                r=sqrt(length(row));
                
            end
            R=zeros(r,r);
            for i=1:r
                for j=1:r
                    R(i,j)=L(row(i),row(j));
                end
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        % Detection using default algorithm
        % default value for samples should be 1
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function Detection = Detect_with_default_algorithm(app, R_oil, frequency)
            
            % the (if app.Calculate_button_pushed == 1) is added here as a
            % condition for the waitbar, so that whenever the detection is
            % done for all frequency pairs/triads not each bar will show
            % individually, instead one waitbar will be showed for all
            
            
            % initializing waitbar
            if app.Calculate_button_pushed == 1
                w = waitbar(0, 'Starting');
            end
            
            
            % Calculating water properties
            E_water_prob = app.E_water(frequency);
            R_water = ones(size(R_oil)) * ((sqrt(app.E_air) - sqrt(E_water_prob))/(sqrt(app.E_air) + sqrt(E_water_prob)))^2;
            R_water = abs(app.coherent_reflectivity(R_water));
            
            
            % Updating waitbar
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 0.2, w, sprintf('Detecting with default algorithm... %d %%', 20));
            end
            
            
            %  Generating noise
            % First & Second dimension  : image resolution
            % Third dimension  : number of frequencies used
            % Fourth dimension : Frequencies at which the scans are done
            
            % generates random values based on the given variance
            noise = sqrt(app.variance) * randn(size(R_oil, 2), size(R_oil, 1), length(frequency), app.samples, app.M);
            
            
            % Updating waitbar
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 0.4, w, sprintf('Detecting with default algorithm... %d %%', 40));
            end
            
            
            % Add the reflectivity values to the noise matrix, thus getting the noisy reflectivity values to be studied
            noisy_reflectivity = noise + R_oil;
            
            
            % Updating waitbar
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 0.6, w, sprintf('Detecting with default algorithm... %d %%', 60));
            end
            
            
            %  Probability of detection calculation
            
            % Calculate the probabilty density functions
            h1 = pdf("Normal", noisy_reflectivity, R_oil, sqrt(app.variance));                                    % Oil
            h2 = pdf("Normal", noisy_reflectivity, R_water, sqrt(app.variance));                                                  % Water
            
            % Multiply the pdf values at all frequencies(f) and scans(M)
            x = prod(h1, 5) * app.pO;
            y = prod(h2, 5) * app.pW;
            
            x = prod(x, 3);
            y = prod(y, 3);
            
            
            % Updating waitbar
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 0.8, w, sprintf('Detecting with default algorithm... %d %%', 80));
            end


            % Check how many oil pdf values are greater than water pdf values
            result = gt(x, y);
            
            % Divide by the total number of trials to find the probability
            Detection(:, :) = sum(result, 4)/double(app.samples);
            
            
            % Updating waitbar
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 1, w, sprintf('Detecting with default algorithm... %d %%', 100));
            end
            
            % Closing waitbar
            if app.Calculate_button_pushed == 1
                close(w);
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Detection using joint pdf for one reflectivity array ( at all
        % frequencies given
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function oil_found = Detect_with_joint_pdf_single_point(app, measured_reflectivity, frequency)
            
            thickness = app.tmin:app.thickness_step:app.tmax;
            
            % theoretical oil reflectivity
            R_oil = app.reflectivity(frequency, thickness);
            
            %  Dielectric constant of water at given frequencies & water reflectivity values at a given surface roughness ks
            E_water_prob = app.E_water(frequency);
            R_water = ((sqrt(app.E_air) - sqrt(E_water_prob))/(sqrt(app.E_air) + sqrt(E_water_prob)))^2;
            R_water = abs(app.coherent_reflectivity(R_water));
            
            
            %  Generating noise
            
            % First dimension  : number of reflectivities (will normally be
            % 1)  since its on a single reflectivity
            % Second dimension : number of frequencies
            % Third dimension : Number of observations
            
            % generates random values based on the given variance
            noise = sqrt(app.variance) * randn(size(measured_reflectivity, 1), size(measured_reflectivity, 2), app.M);
            
            % Add the reflectivity values to the noise matrix, thus getting the noisy reflectivity values to be studied
            noisy_reflectivity = noise + measured_reflectivity;
            
            
            % Calculate the probabilty density functions
            h1 = pdf("Normal", noisy_reflectivity, R_oil, sqrt(app.variance));                          % p(O) is directly included in pdf/length(h1)
            h2 = pdf("Normal", noisy_reflectivity, R_water, sqrt(app.variance)) * app.pW;               % p(W) = 0.5                                                 % Water
            
            x = sum(h1, 2)/length(h1);
            
            % Multiply the pdf values at all frequencies(f) and scans(M)
            x = prod(x, 3);
            y = prod(h2, 3);
            
            x = prod(x, 1);
            y = prod(y, 1);
            
            if x/y >= 1
                oil_found = 1;
            else
                oil_found = 0;
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Detection of multiple points, which uses the previous function to
        % detect along all values of a given matrix
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function oil_found = Detect_with_joint_pdf_multiple_points(app, measured_reflectivity, frequency)
            
            % the (if app.Calculate_button_pushed == 1) is added here as a
            % condition for the waitbar, so that whenever the detection is
            % done for all frequency pairs/triads not each bar will show
            % individually, instead one waitbar will be showed for all
            
            oil_found = double.empty;
            
            % initilaize waitbar
            if app.Calculate_button_pushed == 1
                w = waitbar(0, 'Starting');
            end
            
            % iterate over all values contained in the matrix and detect if
            % oil is found
            for i = 1:1:size(measured_reflectivity, 1)
                for j = 1:1:size(measured_reflectivity, 2)
                    oil_found(i, j) = app.Detect_with_joint_pdf_single_point(reshape(measured_reflectivity(i, j, :), [length(frequency), 1]), frequency);
                end
                
                % update waitbar
                if app.Calculate_button_pushed == 1
                    if ~ishghandle(w)
                        return;
                    end
                    waitbar(i/(size(measured_reflectivity, 1)), w, sprintf('Detecting with joint pdf... %d %%', floor(i/(size(measured_reflectivity, 1))*100)));
                end
                
            end
            
            % close waitbar
            if app.Calculate_button_pushed == 1
                close(w);
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Checks which type of detection is required and calls the suitable
        % function accordingly
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function detection = Detect(app, measured_reflectivity, frequency)
            
            if app.Jointpdf.Value == 1
                detection = app.Detect_with_joint_pdf_multiple_points(measured_reflectivity, frequency);
            else
                detection = app.Detect_with_default_algorithm(measured_reflectivity, frequency);
            end
            
            detected_zeros = find(detection == 0);
            actual_zeros = find(app.G == 0);
            
            detected_ones = find(detection > 0);
            actual_ones = find(app.G > 0);
            
            [val_zeros, ~] = intersect(detected_zeros, actual_zeros);
            [val_ones, ~] = intersect(detected_ones, actual_ones);
            
            app.DetectorErrorPercentageEditField.Value = ((length(actual_ones) + length(actual_zeros) - length(val_ones) - length(val_zeros))/(length(actual_ones) + length(actual_zeros))) * 100 ;
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        % Detecting if oil is found using pre-defined frequency triads that are
        % calculated to be the best traids on each thickness
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function Detect_for_triads(app)
            
            % Pre-defined frequency triads
            f = [[5 12 12]; [6 12 12]; [9 9 12]; [7 9 12]; [9 12 12]; [8 10 10]; [7 9 9]; [7 8 12]; [4 12 12]; [4 11 12]];
            
            reflectivity_values = double.empty;
            DetectionValues = double.empty;
            
            % initializing waitbar
            w = waitbar(0, 'Starting');
            
            % Calculating the detected results based on what algorithm is
            % chosen ( Default / Joint pdf)
            for i = 1:1:size(f, 1)
                
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = app.reflectivity(f(i, j), app.G);
                end
                
                if app.Jointpdfdetection == 1
                    DetectionValues(:, :, i) = app.Detect_with_joint_pdf_multiple_points(reflectivity_values, f(i, :));
                else
                    DetectionValues(:, :, i) = app.Detect_with_default_algorithm(reflectivity_values, f(i, :));
                end
                
                % updating waitbar
                if ~ishghandle(w)
                    return;
                end
                
                if app.Jointpdfdetection == 1
                    waitbar(i/size(f, 1), w, sprintf('Detecting with Joint pdf... %d %%', floor(i/size(f, 1)*100)));
                else
                    waitbar(i/size(f, 1), w, sprintf('Detecting with default algorithm... %d %%', floor(i/size(f, 1)*100)));
                end
                
            end
            
            % closing waitbar
            close(w);
            
            % transferring the data to the Plots application where the
            % results will be plotted
            app.Plots = Plots(app, DetectionValues, app.tmin, app.tmax, size(f,2), app.options);
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Detecting if oil is found using pre-defined frequency pairs that are
        % calculated to be the best pairs on each thickness
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function Detect_for_pairs(app)
            
            % Pre-defined frequency pairs
            f = [[6 12]; [6 12]; [4 12]; [10 11]; [9 9]; [8 12]; [7 10]; [6 8]; [4 8]; [4 12]];
            
            reflectivity_values = double.empty;
            DetectionValues = double.empty;
            
            % initializing waitbar
            w = waitbar(0, 'Starting');
            
            % Calculating the detected results based on what algorithm is
            % chosen ( Default / Joint pdf)
            for i = 1:1:size(f, 1)
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = app.reflectivity(f(i, j), app.G);
                end
                
                if app.Jointpdfdetection == 1
                    DetectionValues(:, :, i) = app.Detect_with_joint_pdf_multiple_points(reflectivity_values, f(i, :));
                else
                    DetectionValues(:, :, i) = app.Detect_with_default_algorithm(reflectivity_values, f(i, :));
                end
                
                % Updating waitbar
                if ~ishghandle(w)
                    return;
                end
                if app.Jointpdfdetection == 1
                    waitbar(i/size(f, 1), w, sprintf('Detecting with Joint pdf... %d %%', floor(i/size(f, 1)*100)));
                else
                    waitbar(i/size(f, 1), w, sprintf('Detecting with default algorithm... %d %%', floor(i/size(f, 1)*100)));
                end
                
            end
            
            % closing waitbar
            close(w);
            
            % transferring the data to the Plots application where the
            % results will be plotted
            app.Plots = Plots(app, DetectionValues, app.tmin, app.tmax, size(f,2), app.options);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        %   Probability of detection default algorithm
        %  default value of samples should be 1 in this algorithm
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function probability_of_detection = probability_of_detection(app)
            
            thickness_range = app.tmin:app.thickness_step:app.tmax;         %thickness over which the reflectivities will be calculated
            
            
            %  Calculate reflectivities for given frequencies, thicknesses, and surface roughness
            R_oil = app.reflectivity(app.frequency, thickness_range);


            %  Dielectric constant of water at given frequencies & water reflectivity values at a given surface roughness ks
            E_water_prob = app.E_water(app.frequency);
            R_water_fprob_planar = ones(size(R_oil)) * ((sqrt(app.E_air) - sqrt(E_water_prob))/(sqrt(app.E_air) + sqrt(E_water_prob)))^2;
            R_water_fprob_planar = app.coherent_reflectivity(R_water_fprob_planar);
            
            
            %  Generating noise
            
            % First dimension  : thickness values
            % Second dimension : Noise values
            % Third dimension  : Values at different scans (M)
            % Fourth dimension : Frequencies at which the scans are done
            
            % generates random values based on the given variance
            noise = sqrt(app.variance) * randn(size(R_oil, 2), app.samples, app.M, size(R_oil, 1));
            
            % transpose the theoretical reflectivity to add the reflectivity values to the noise generated (each reflecctivity for its correspondant thickness)
            A = transpose(abs(R_oil));
            
            % reshape the reflectivity values to suit the dimensions of the noise
            % matrix
            A = reshape(A , [size(R_oil, 2), 1, 1, size(noise, 4)]);
            
            % Add the reflectivity values to the noise matrix, thus getting the noisy reflectivity values to be studied
            noisy_reflectivity = noise + A;
            
            
            %  Probability of detection calculation
            
            % Calculate the probabilty density functions
            h1 = pdf("Normal", noisy_reflectivity, A, sqrt(app.variance));                                    % Oil
            h2 = pdf("Normal", noisy_reflectivity, abs(R_water_fprob_planar(1,1)), sqrt(app.variance));       % Water
            
            % Multiply the pdf values at all frequencies(f) and scans(M)
            x = prod(h1, 4) * app.pO;
            y = prod(h2, 4) * app.pW;
            
            x = prod(x, 3);
            y = prod(y, 3);
            
            % Check how many oil pdf values are greater than water pdf values
            result = gt(x, y);
            
            % Divide by the total number of trials to find the probability
            probability_of_detection(:, :) = sum(transpose(result(:, :)) == 1)/double(app.samples);
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Detection probability for experimental results
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function Detect_for_experimental_results(app, measured_reflectivity)
            
            measured_reflectivity = permute(measured_reflectivity, [3 2 1]);
            
            thickness = app.tmin:app.thickness_step:app.tmax;
            
            % theoretical oil reflectivity
            R_oil = app.reflectivity(app.frequency, thickness);
            
            %  Dielectric constant of water at given frequencies & water reflectivity values at a given surface roughness ks
            E_water_prob = app.E_water(app.frequency);
            R_water = ((sqrt(app.E_air) - sqrt(E_water_prob))/(sqrt(app.E_air) + sqrt(E_water_prob)))^2;
            R_water = abs(app.coherent_reflectivity(R_water));
            
            
            %  Generating noise
            
            % First dimension  : number of reflectivities (will normally be
            % 1)  since its on a single reflectivity
            % Second dimension : number of frequencies
            % Third dimension : Number of observations
            
            
            % Calculate the probabilty density functions
            h1 = pdf("Normal", measured_reflectivity, R_oil, sqrt(app.variance));                          % p(O) is directly included in pdf/length(h1)
            h2 = pdf("Normal", measured_reflectivity, R_water, sqrt(app.variance)) * app.pW;               % p(W) = 0.5                                                 % Water
            
            x = sum(h1, 2)/length(h1);
            
            % Multiply the pdf values at all frequencies(f) and scans(M)
            x = prod(x, 3);
            y = prod(h2, 3);
            
            x = prod(x, 1);
            y = prod(y, 1);


            if x/y >= 1
                oil_found = 1;
            else
                oil_found = 0;
            end
            
            app.ProbabilityOfOilDetectionEditField.Value = oil_found;
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Minimum Euclidean Distance algorithm
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function closest_thickness = minimum_euclidean_distance(app, measured_reflectivity, frequency)
            
            % thickness over which the reflectivities will be calculated
            thickness = app.tmin:app.thickness_step:app.tmax;
            
            % theoretical curve by which the measured reflectivities are compared
            R_oil = 10*log10(abs(app.reflectivity(frequency, thickness)));
            
            
            % Finding the point closest to the measured reflectivity
            distance = sqrt(sum((R_oil-transpose(measured_reflectivity)).^2, 1));
            [~ , indexOfThickness] = min(distance);
            closest_thickness = thickness(indexOfThickness);
            
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Thickness estimation
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function Estimated_thicknesses = Estimate_Thickness(app, measured_reflectivity, frequency)
            
            % the (if app.Calculate_button_pushed == 1) is added here as a
            % condition for the waitbar, so that whenever the detection is
            % done for all frequency pairs/triads not each bar will show
            % individually, instead one waitbar will be showed for all
            
            
            % initializing waitbar
            if app.Calculate_button_pushed == 1
                w = waitbar(0, 'Starting');
            end
            
            
            % Simulate the M observations
            
            % generate noise M times to simulate the collection of different observations
            noise = sqrt(app.variance) * randn(size(measured_reflectivity, 1), size(measured_reflectivity, 2), length(frequency), app.M);
            measured_reflectivity = measured_reflectivity + noise;
            measured_reflectivity = sum(measured_reflectivity, 4)/app.M;
            
            
            %% Estimation & Histogram
            Estimated_thicknesses = double.empty;
            
            %   use minimum euclidean distance to find estimate the thickness on
            %   each reflectvity value collected
            
            for i = 1:1:size(measured_reflectivity, 1)
                for j = 1:1:size(measured_reflectivity, 2)
                    for k = 1:1:size(measured_reflectivity, 3)
                        ref = 10*log10(measured_reflectivity(i, j, :));
                        Estimated_thicknesses(i, j) = app.minimum_euclidean_distance(reshape(ref, [1, size(ref, 3)]), frequency);
                    end
                end
                
                % update waitbar
                if app.Calculate_button_pushed == 1
                    if ~ishghandle(w)
                        return;
                    end
                    waitbar(i/(size(measured_reflectivity, 1)), w, sprintf('Estimating... %d %%', floor(i/(size(measured_reflectivity, 1))*100)));
                end
            end
            
            app.Estimated_thicknesses = Estimated_thicknesses;
            
            % close waitbar
            if app.Calculate_button_pushed == 1
                close(w);
            end
            
            app.CheckEstimatedError(Estimated_thicknesses, app.G);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Thickness Estimation for experimental data
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function Experimental_thickness_estimation(app, measured_reflectivities)
            
            app.h = app.Estimate_Thickness(measured_reflectivities, app.frequency);
            app.s = histogram(app.UIAxes, app.h);
            app.UIAxes.XLim = [app.tmin , app.tmax+1];
            grid(app.UIAxes, 'off');
            
            app.analyze_histogram();
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Estimates thickness for all pre-defined frequency triads that are
        % calculated to be the best traids on each thickness
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function Estimate_for_triads(app)
            
            % pre-defined frequency triads
            f = [[5 12 12]; [6 12 12]; [9 9 12]; [7 9 12]; [9 12 12]; [8 10 10]; [7 9 9]; [7 8 12]; [4 12 12]; [4 11 12]];
            
            
            reflectivity_values = double.empty;
            EstimationValues = double.empty;
            
            % initializing waiting bar
            w = waitbar(0, 'Starting');
            
            % Estimating the thicknesses at each frequency triad
            for i = 1:1:size(f, 1)
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = app.reflectivity(f(i, j), app.G);
                end
                EstimationValues(:, :, i) = app.Estimate_Thickness(reflectivity_values, f(i, :));
                
                % updating waiting bar
                if ~ishghandle(w)
                    return;
                end
                waitbar(i/size(f, 1), w, sprintf('Estimating... %d %%', floor(i/size(f, 1)*100)));
            end
            
            % closing waitbar
            close(w);
            
            
            % transferring the data to the Plots application where the
            % results will be plotted
            app.Plots = Plots(app, EstimationValues, app.tmin, app.tmax, size(f,2), app.options);
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        % Estimates thickness for all pre-defined frequency pairs that are
        % calculated to be the best pairs on each thickness
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function Estimate_for_pairs(app)
            
            % pre-defined frequency pairs
            f = [[6 12]; [6 12]; [4 12]; [10 11]; [9 9]; [8 12]; [7 10]; [6 8]; [4 8]; [4 12]];
            
            reflectivity_values = double.empty;
            EstimationValues = double.empty;
            
            % Initializing waitbar
            w = waitbar(0, 'Starting');
            
            % Estimating the thicknesses at each frequency pair
            for i = 1:1:size(f, 1)
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = app.reflectivity(f(i, j), app.G);
                end
                EstimationValues(:, :, i) = app.Estimate_Thickness(reflectivity_values, f(i, :));
                
                % Updating waitbar
                if ~ishghandle(w)
                    return;
                end
                waitbar(i/size(f, 1), w, sprintf('Estimating... %d %%', floor(i/size(f, 1)*100)));
                
            end
            
            % closing waitbar
            close(w);
            
            % transferring the data to the Plots application where the
            % results will be plotted
            app.Plots = Plots(app, EstimationValues, app.tmin, app.tmax, size(f,2), app.options);
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Check the error in the estiomation algorithm
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function CheckEstimatedError(app, Estimated_thickness, Actual_thickness)
            
            correctly_estimated_upper = find(Estimated_thickness <= (Actual_thickness + app.EstimationerrormarginSpinner.Value));
            correctly_estimated_lower = find(Estimated_thickness >= (Actual_thickness - app.EstimationerrormarginSpinner.Value));
            correctly_estimated = intersect(correctly_estimated_lower, correctly_estimated_upper);
            
            error = (((size(app.G, 1) * size(app.G, 2)) - length(correctly_estimated))/(size(app.G, 1) * size(app.G, 2))) * 100;
            app.EstimatorErrorPercentageEditField.Value = error;
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Minimum Euclidean Distance algorithm
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function scatter_points(app)
            
            % Erasing data from first axes
            cla(app.UIAxes, 'reset');
            
            % thickness over which the reflectivities will be calculated
            thickness = app.tmin:app.thickness_step:app.tmax;
            
            % theoretical oil reflectivity values by which the measured reflectivities are compared
            R_oil = abs(app.reflectivity(app.frequency, thickness));
            
            
            % Generating the random reflectivities with given frequencies and at a given thickness to test the frequencies
            noise = sqrt(app.variance)*randn(length(app.frequency), int64(app.samples), app.M);
            [~, indexOfThickness] = min(abs(thickness-app.desired_thickness));
            noisy_reflectivities = 10*log10(abs(R_oil(:, indexOfThickness) + noise));
            app.scattered_values = sum(noisy_reflectivities, 3)/app.M;
            
            
            % Scattering on axes
            
            if length(app.frequency) == 2
                
                % 2D Scatter plots
                scatter(app.UIAxes, app.scattered_values(1, :), app.scattered_values(2, :), 'x');
                hold(app.UIAxes, 'on');
                scatter(app.UIAxes, 10*log10(abs(R_oil(1, :))), 10*log10(abs(R_oil(2, :))), '.');
                grid(app.UIAxes, 'on');
                app.UIAxes.XLim = [-8 0];
                app.UIAxes.XLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(1)), "GHz");
                app.UIAxes.YLim = [-8 0];
                app.UIAxes.YLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(2)), "GHz");
                
                
                % Plotting thickness regions on scatterplot is case only 2 frequencies are used and the thickness size is above 0.5 mm, not to be too crowded
                if app.thickness_step >= 0.5
                    SymbolSet = zeros(size(R_oil,2),1);
                    SymbolSet(:,1)= 10*log10(R_oil(1,:).');
                    SymbolSet(:,2)= 10*log10(R_oil(2,:).');
                    [NumOfSignal, ~] = size(SymbolSet);
                    ProbSet = ones(1,NumOfSignal)/NumOfSignal; % probability of each symbol
                    No = 2;
                    % Generate the Decision Region Diagram of the input Signal Symbol Set
                    app.SignalSymbolDecisionRegionGenerator(app.UIAxes, SymbolSet, ProbSet, No);
                    plot(app.UIAxes, SymbolSet(:,1), SymbolSet(:,2));
                    
                end
                
                hold(app.UIAxes, 'off');
                
                
            elseif length(app.frequency) == 3
                
                % 3D Scatter plots
                scatter3(app.UIAxes, app.scattered_values(1, :), app.scattered_values(2, :), app.scattered_values(3, :), 'x');
                hold(app.UIAxes, 'on');
                scatter3(app.UIAxes, 10*log10(abs(R_oil(1, :))), 10*log10(abs(R_oil(2, :))), 10*log10(abs(R_oil(3, :))), '.');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                app.UIAxes.XLim = [-8 0];
                app.UIAxes.XLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(1)), "GHz");
                app.UIAxes.YLim = [-8 0];
                app.UIAxes.YLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(2)), "GHz");
                app.UIAxes.ZLim = [-8 0];
                app.UIAxes.ZLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(3)), "GHz");
                
            else
                
                % Only the histogram is shown in case more than 3
                % frequencies are given
                app.UIAxes.Visible = "off";
                app.UIAxes2.Position = [69,226,560,456];
                
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Generate regions to be plotted according to the given 2
        % frequencies
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function SignalSymbolDecisionRegionGenerator(app, ax, SymbolSet, ProbSet, No)
            
            
            %{
        /***************************************************************************
                 M-Ary Quadrature Signal Modulation Decision Region Generator
                                     -------------------
            begin                : 2013-08-16
            copyright            : (C) 2013 by Walter Tsui
            email                : waltertech426@gmail.com
         ***************************************************************************/
        /***************************************************************************
         *                                                                         *
         *   This program is free software; you can redistribute it and/or modify  *
         *   it under the terms of the GNU General Public License as published by  *
         *   the Free Software Foundation; either version 2 of the License, or     *
         *   (at your option) any later version.                                   *
         *                                                                         *
         ***************************************************************************/
            %}
            
            %Generate Decision Region diagram for any number of Signal Symbol points with different probability on 2-dimensional signal plane
            
            %No: noise power spectral density
            
            %Check input argument
            %if(sum(ProbSet) ~= 1)
            %    sum(ProbSet) ~= 1
            %    sum(ProbSet) > 1
            %    sum(ProbSet) < 1
            %    display('total probability of the symbol is not equal to one !')
            %    return;
            %end
            
            
            [NumOfSignal, dim] = size(SymbolSet);
            
            %Diagram Initialization
            DiagBdry_min = zeros(dim);
            DiagBdry_max = zeros(dim);
            
            DiagBdryOffset = 2;
            
            for i = 1:dim
                %{
        DiagBdry_min(1) = min(SymbolSet(:, i)) - abs(min(SymbolSet(:, i)));
        DiagBdry_max(1) = max(SymbolSet(:, i)) + abs(max(SymbolSet(:, i)));
                %}
                
                DiagBdry_min(i) = min(SymbolSet(:, i)) - DiagBdryOffset;
                DiagBdry_max(i) = max(SymbolSet(:, i)) + DiagBdryOffset;
            end


            plot(ax, SymbolSet(:, 1), SymbolSet(:, 2), 'o');
            %             ylim([DiagBdry_min(2) DiagBdry_max(2)]);
            %             xlim([DiagBdry_min(1) DiagBdry_max(1)]);
            DiagBdry_x = [DiagBdry_min(1):0.1:DiagBdry_max(1)];
            
            hold(ax, "on");
            
            
            %Decision Boundary Calculation Initialization
            Bdry_DistSet = zeros(NumOfSignal, NumOfSignal);
            Bdry_CoorSet = zeros(NumOfSignal, NumOfSignal, dim);
            Bdry_SlopeSet = zeros(NumOfSignal, NumOfSignal);
            Bdry_Y_IntcpSet = zeros(NumOfSignal, NumOfSignal);
            
            %Each boundary line is presented as two end points at the Diagram Boundary , start_Coor and Bdry_EndCoor,  as it is a straight line
            Bdry_S_CoorSet = zeros(NumOfSignal, NumOfSignal, dim);
            Bdry_E_CoorSet = zeros(NumOfSignal, NumOfSignal, dim);
            
            
            %Calculate required parameters of each boundary line
            for i = 1:NumOfSignal
                for j = 1:NumOfSignal
                    if i == j
                        continue;
                    end
                    
                    [Bdry_DistSet(i, j), Bdry_CoorSet(i, j, :), Bdry_SlopeSet(i, j), Bdry_Y_IntcpSet(i, j)] = app.SignalSymbolDecisionBdry(SymbolSet(i, :), SymbolSet(j, :), ProbSet(i), ProbSet(j), No);
                    
                    %Determine the two end points of the boundary
                    if(Bdry_SlopeSet(i, j) == Inf)
                        Bdry_S_CoorSet(i, j, 1) = Bdry_CoorSet(i, j, 1);
                        Bdry_E_CoorSet(i, j, 1) = Bdry_CoorSet(i, j, 1);
                        Bdry_S_CoorSet(i, j, 2) = DiagBdry_min(2);
                        Bdry_E_CoorSet(i, j, 2) = DiagBdry_max(2);
                        
                    else
                        Bdry_S_CoorSet(i, j, 1) = DiagBdry_min(1);
                        Bdry_E_CoorSet(i, j, 1) = DiagBdry_max(1);
                        Bdry_S_CoorSet(i, j, 2) = Bdry_SlopeSet(i, j) * DiagBdry_min(1) + Bdry_Y_IntcpSet(i, j);
                        Bdry_E_CoorSet(i, j, 2) = Bdry_SlopeSet(i, j) * DiagBdry_max(1) + Bdry_Y_IntcpSet(i, j);
                        
                    end
                end
            end
            
            
            %Calculate the intercept of each pair of boundary lines of a signal for all
            %signals
            for i = 1:NumOfSignal
                for j = 1:NumOfSignal
                    if i == j
                        continue;
                    end
                    
                    for k = 1:NumOfSignal
                        if(k == j || k == i)
                            continue;
                        end
                        
                        if(Bdry_SlopeSet(i, j) == Bdry_SlopeSet(i, k))
                            Intcp_x = NaN;
                            Intcp_y = NaN;
                            
                        else
                            if(Bdry_SlopeSet(i, j) == Inf && Bdry_SlopeSet(i, k) ~= Inf)
                                Intcp_x = Bdry_CoorSet(i, j, 1);
                                Intcp_y = Bdry_SlopeSet(i, k) * Intcp_x + Bdry_Y_IntcpSet(i, k);
                                
                            elseif(Bdry_SlopeSet(i, j) ~= Inf && Bdry_SlopeSet(i, k) == Inf)
                                Intcp_x = Bdry_CoorSet(i, k, 1);
                                Intcp_y = Bdry_SlopeSet(i, j) * Intcp_x + Bdry_Y_IntcpSet(i, j);
                                
                            else
                                %line 1: y = ax + b
                                %line 2: y = cx + d
                                %=> x = (b-d)/(a-c)
                                Intcp_x = (Bdry_Y_IntcpSet(i, j) - Bdry_Y_IntcpSet(i, k))/(Bdry_SlopeSet(i, k) - Bdry_SlopeSet(i, j));
                                
                                if(Bdry_SlopeSet(i, k) == 0)
                                    Intcp_y = Bdry_Y_IntcpSet(i, k);
                                    
                                else
                                    Intcp_y = Bdry_SlopeSet(i, j) * Intcp_x + Bdry_Y_IntcpSet(i, j);
                                    
                                end
                                
                            end
                            
                        end
                        
                        
                        u = strcat('Intcp_x_', num2str(i), '_', num2str(j));
                        u = strcat(u, '_', num2str(i), '_', num2str(k));
                        eval([u ' = Intcp_x;']);
                        
                        u = strcat('Intcp_y_', num2str(i), '_', num2str(j));
                        u = strcat(u, '_', num2str(i), '_', num2str(k));
                        eval([u ' = Intcp_y;']);
                        
                    end
                end
            end
            
            
            %Cut the boundary line by vector method
            for i = 1:NumOfSignal
                %break;
                
                for j = 1:NumOfSignal
                    if i == j
                        continue;
                    end
                    
                    w = strcat(num2str(i), '_', num2str(j));
                    
                    for k = 1:NumOfSignal
                        if(k == i || k == j)
                            continue;
                        end
                        
                        
                        %Select Boundary lines if the two slopes are equal
                        if(Bdry_SlopeSet(i, j) == Bdry_SlopeSet(i, k))
                            if(Bdry_Y_IntcpSet(i, j) == Bdry_Y_IntcpSet(i, k))
                                Bdry_S_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                Bdry_E_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                
                                continue;
                                
                            end
                            
                            %Calculate the two vectors from the signal symbols to the two boundary lines
                            v_SToB1 = [Bdry_CoorSet(i, j, 1) - SymbolSet(i, 1), Bdry_CoorSet(i, j, 2) - SymbolSet(i, 2)];
                            v_SToB2 = [Bdry_CoorSet(i, k, 1) - SymbolSet(i, 1), Bdry_CoorSet(i, k, 2) - SymbolSet(i, 2)];
                            
                            len_v_SToB1 = sqrt(sum(v_SToB1.^2));
                            len_v_SToB2 = sqrt(sum(v_SToB2.^2));
                            
                            v_SToB1 = v_SToB1/len_v_SToB1;
                            v_SToB2 = v_SToB2/len_v_SToB2;
                            
                            if (v_SToB1 == v_SToB2)
                                if (Bdry_DistSet(i, j) > Bdry_DistSet(i, k))
                                    Bdry_S_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                    Bdry_E_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                    
                                else
                                    Bdry_S_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                    Bdry_E_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                    
                                end
                            end
                            
                            continue;
                            
                        end
                        
                        %Cut Boundary lines from their end pts to their intercept
                        v = strcat(w, '_', num2str(i), '_', num2str(k));
                        
                        u = strcat('Intcp_x_', v);
                        eval(['Intcp_x = ' u ';']);
                        
                        u = strcat('Intcp_y_', v);
                        eval(['Intcp_y = ' u ';']);
                        
                        if( Intcp_x == nan || Intcp_y == nan)
                            continue;
                        end
                        
                        %The angle between boundary lines and angle between each of the boundary and the symbol signal can determine the correct boundary
                        %Calculate the vectors of the two boundary lines intercepted
                        %line: y = ax + c => vector: i + aj
                        if(Bdry_SlopeSet(i, j) == Inf)
                            vector_1 = [0, 1];
                        else
                            vector_1 = [1, Bdry_SlopeSet(i, j)];
                        end
                        
                        if(Bdry_SlopeSet(i, k) == Inf)
                            vector_2 = [0, 1];
                        else
                            vector_2 = [1, Bdry_SlopeSet(i, k)];
                        end
                        
                        %Calculate the Angle and Supplement Angle between the two boundary vectors
                        %By Dot Product Equation: v.u = |v||u|cos(a), 0 <= a <= pi
                        %=> a = acos((v.u)/(|v||u|))
                        len_v1 = sqrt(sum(vector_1.^2));
                        len_v2 = sqrt(sum(vector_2.^2));
                        
                        v1v2_angle = sum(vector_1.* vector_2)/(len_v1 * len_v2);
                        v1v2_angle = acos(v1v2_angle);
                        v1v2_supp_angle = pi - v1v2_angle;
                        
                        %Create a vector from the signal symbol to the intercept
                        v_IntcpToSigSym = [SymbolSet(i, 1) - Intcp_x, SymbolSet(i, 2) - Intcp_y];
                        len_i2s = sqrt(sum(v_IntcpToSigSym.^2));
                        
                        v1vi2s_angle = sum(vector_1.* v_IntcpToSigSym)/(len_v1 * len_i2s);
                        v1vi2s_angle = acos(v1vi2s_angle);
                        v1vi2s_supp_angle = pi - v1vi2s_angle;
                        
                        v2vi2s_angle = sum(vector_2.* v_IntcpToSigSym)/(len_v2 * len_i2s);
                        v2vi2s_angle = acos(v2vi2s_angle);
                        v2vi2s_supp_angle = pi - v2vi2s_angle;
                        
                        %Create Vectors from Start to intercept and intercept to end for checking whether the intercept is within the current truncated boundary
                        v_S1ToIntcp = [Intcp_x - Bdry_S_CoorSet(i, j, 1), Intcp_y - Bdry_S_CoorSet(i, j, 2)];
                        v_E1ToIntcp = [Intcp_x - Bdry_E_CoorSet(i, j, 1), Intcp_y - Bdry_E_CoorSet(i, j, 2)];
                        
                        Dist_S1ToIntcp = sqrt(sum(v_S1ToIntcp.^2));
                        Dist_E1ToIntcp = sqrt(sum(v_E1ToIntcp.^2));
                        
                        %{
                vS1ToInt_E1ToInt_angle = sum(v_S1ToIntcp.* v_E1ToIntcp)/(Dist_S1ToIntcp * Dist_E1ToIntcp);
                vS1ToInt_E1ToInt_angle = acos(vS1ToInt_E1ToInt_angle);
                
                if (vS1ToInt_E1ToInt_angle == NaN)
                    vS1ToInt_E1ToInt_angle = pi;
                end
                        %}
                        
                        Dist_S1ToE1 = sqrt((Bdry_E_CoorSet(i, j, 1) - Bdry_S_CoorSet(i, j, 1))^2 + (Bdry_E_CoorSet(i, j, 2) - Bdry_S_CoorSet(i, j, 2))^2);
                        
                        
                        v_S2ToIntcp = [Intcp_x - Bdry_S_CoorSet(i, k, 1), Intcp_y - Bdry_S_CoorSet(i, k, 2)];
                        v_E2ToIntcp = [Intcp_x - Bdry_E_CoorSet(i, k, 1), Intcp_y - Bdry_E_CoorSet(i, k, 2)];
                        
                        Dist_S2ToIntcp = sqrt(sum(v_S2ToIntcp.^2));
                        Dist_E2ToIntcp = sqrt(sum(v_E2ToIntcp.^2));
                        
                        %{
                vS2ToInt_E2ToInt_angle = sum(v_S2ToIntcp.* v_E2ToIntcp)/(Dist_S2ToIntcp * Dist_E2ToIntcp);
                vS2ToInt_E2ToInt_angle = acos(vS2ToInt_E2ToInt_angle);
                
                if (vS2ToInt_E2ToInt_angle == NaN)
                    vS2ToInt_E2ToInt_angle = pi;
                end
                        %}
                        
                        Dist_S2ToE2 = sqrt((Bdry_E_CoorSet(i, k, 1) - Bdry_S_CoorSet(i, k, 1))^2 + (Bdry_E_CoorSet(i, k, 2) - Bdry_S_CoorSet(i, k, 2))^2);
                        
                        
                        %%{
                        if((v1vi2s_angle < v1v2_angle) && (v2vi2s_angle < v1v2_angle) && (v1vi2s_angle + v2vi2s_angle < pi))
                            if(Dist_S1ToE1 > 0)
                                %if(vS1ToInt_E1ToInt_angle == pi)
                                if(Dist_S1ToE1 > Dist_E1ToIntcp)
                                    Bdry_S_CoorSet(i, j, 1) = Intcp_x;
                                    Bdry_S_CoorSet(i, j, 2) = Intcp_y;
                                    
                                elseif(Dist_S1ToIntcp > Dist_E1ToIntcp)
                                    Bdry_S_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                    Bdry_E_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                end
                            end
                            
                            if(Dist_S2ToE2 > 0)
                                %if(vS2ToInt_E2ToInt_angle == pi)
                                if(Dist_S2ToE2 > Dist_E2ToIntcp)
                                    Bdry_S_CoorSet(i, k, 1) = Intcp_x;
                                    Bdry_S_CoorSet(i, k, 2) = Intcp_y;
                                    
                                elseif(Dist_S2ToIntcp > Dist_E2ToIntcp)
                                    Bdry_S_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                    Bdry_E_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                end
                            end
                            
                            %end
                            
                            %if((v1vi2s_angle + v2vi2s_angle > pi) && (v1vi2s_supp_angle < v1v2_angle) && (v2vi2s_supp_angle < v1v2_angle))
                        elseif((v1vi2s_angle + v2vi2s_angle > pi) && (v1vi2s_supp_angle < v1v2_angle) && (v2vi2s_supp_angle < v1v2_angle))
                            if(Dist_S1ToE1 > 0)
                                %if(vS1ToInt_E1ToInt_angle == pi)
                                if(Dist_S1ToE1 > Dist_S1ToIntcp)
                                    Bdry_E_CoorSet(i, j, 1) = Intcp_x;
                                    Bdry_E_CoorSet(i, j, 2) = Intcp_y;
                                    
                                elseif(Dist_S1ToIntcp < Dist_E1ToIntcp)
                                    Bdry_S_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                    Bdry_E_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                end
                            end
                            
                            if(Dist_S2ToE2 > 0)
                                %if(vS2ToInt_E2ToInt_angle == pi)
                                if(Dist_S2ToE2 > Dist_S2ToIntcp)
                                    Bdry_E_CoorSet(i, k, 1) = Intcp_x;
                                    Bdry_E_CoorSet(i, k, 2) = Intcp_y;
                                    
                                elseif(Dist_S2ToIntcp < Dist_E2ToIntcp)
                                    Bdry_S_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                    Bdry_E_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                end
                            end
                            
                            %end
                            
                            %if(v1vi2s_angle > v1v2_angle && v1vi2s_angle > v2vi2s_angle && v2vi2s_angle + v1v2_angle < pi)
                        elseif(v1vi2s_angle > v1v2_angle && v1vi2s_angle > v2vi2s_angle)
                            if(Dist_S1ToE1 > 0)
                                %if(vS1ToInt_E1ToInt_angle == pi)
                                if(Dist_S1ToE1 > Dist_S1ToIntcp)
                                    Bdry_E_CoorSet(i, j, 1) = Intcp_x;
                                    Bdry_E_CoorSet(i, j, 2) = Intcp_y;
                                    
                                elseif(Dist_S1ToIntcp < Dist_E1ToIntcp)
                                    Bdry_S_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                    Bdry_E_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                end
                            end
                            
                            if(Dist_S2ToE2 > 0)
                                if(Dist_S2ToE2 > Dist_E2ToIntcp)
                                    %if(vS2ToInt_E2ToInt_angle == pi)
                                    Bdry_S_CoorSet(i, k, 1) = Intcp_x;
                                    Bdry_S_CoorSet(i, k, 2) = Intcp_y;
                                    
                                elseif(Dist_S2ToIntcp > Dist_E2ToIntcp)
                                    Bdry_S_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                    Bdry_E_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                end
                            end
                            
                            %end
                            
                            %if(v2vi2s_angle > v1v2_angle && v2vi2s_angle > v2vi2s_angle && v1vi2s_angle + v1v2_angle < pi)
                            %elseif(v2vi2s_angle > v1v2_angle && v2vi2s_angle > v1vi2s_angle)
                        else
                            if(Dist_S1ToE1 > 0)
                                %if(vS1ToInt_E1ToInt_angle == pi)
                                if(Dist_S1ToE1 > Dist_E1ToIntcp)
                                    Bdry_S_CoorSet(i, j, 1) = Intcp_x;
                                    Bdry_S_CoorSet(i, j, 2) = Intcp_y;
                                    
                                elseif(Dist_S1ToIntcp > Dist_E1ToIntcp)
                                    Bdry_S_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                    Bdry_E_CoorSet(i, j, :) = Bdry_CoorSet(i, j, :);
                                end
                            end
                            
                            if(Dist_S2ToE2 > 0)
                                %if(vS2ToInt_E2ToInt_angle == pi)
                                if(Dist_S2ToE2 > Dist_S2ToIntcp)
                                    Bdry_E_CoorSet(i, k, 1) = Intcp_x;
                                    Bdry_E_CoorSet(i, k, 2) = Intcp_y;
                                    
                                elseif(Dist_S2ToIntcp < Dist_E2ToIntcp)
                                    Bdry_S_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                    Bdry_E_CoorSet(i, k, :) = Bdry_CoorSet(i, k, :);
                                end
                            end
                            
                        end
                        
                        %%}
                    end
                end
            end
            
            
            %Draw the resultant boundary lines
            for i = 1:NumOfSignal
                for j = 1:NumOfSignal
                    if i == j
                        continue;
                    end
                    
                    %                     switch i
                    %                         case 1
                    %                             color = 'r';
                    %
                    %                         case 2
                    %                             color = 'g';
                    %
                    %                         case 3
                    %                             color = 'b';
                    %
                    %                         case 4
                    %                             color = 'k';
                    %                     end
                    
                    %{
            if(i == 1)
                color = 'r';
                
            elseif(i == 2)
                color = 'g';
                
            elseif(i == 3)
                color = 'b';
                
            elseif(i == 4)
                color = 'k';
                
            end
                    %}
                    
                    color = 'k';
                    
                    if(Bdry_SlopeSet(i, j) == Inf)
                        %line([Bdry_Y_IntcpSet;Bdry_Y_IntcpSet], [DiagBdry_min(2);DiagBdry_max(2)]);
                        line( ax, [Bdry_Y_IntcpSet(i, j);Bdry_Y_IntcpSet(i, j)], [Bdry_S_CoorSet(i, j, 2);Bdry_E_CoorSet(i, j, 2)]);
                        
                    else
                        %boundary = Bdry_SlopeSet(i, j) * DiagBdry_x + Bdry_Y_IntcpSet(i, j);
                        DiagBdry_x = [Bdry_S_CoorSet(i, j, 1):0.01:Bdry_E_CoorSet(i, j, 1)];
                        DiagBdry_y = Bdry_SlopeSet(i, j) * DiagBdry_x + Bdry_Y_IntcpSet(i, j);
                        plot( ax, DiagBdry_x, DiagBdry_y, color);
                        %line([DiagBdry_min(1), DiagBdry_max(1)], [(Bdry_SlopeSet * DiagBdry_min(1) + Bdry_Y_IntcpSet), (Bdry_SlopeSet * DiagBdry_max(1) + Bdry_Y_IntcpSet)])
                        
                    end
                    
                end
            end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        % Used to decide the region boundaries in the function above
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function [BdryDistFrmS1, Bdry_Coor, Bdry_Slope, Bdry_Y_Intcp] = SignalSymbolDecisionBdry(app, s1, s2, prob_1, prob_2, No)
            
            %{
        /***************************************************************************
                 M-Ary Quadrature Signal Modulation Decision Region Generator
                                     -------------------
            begin                : 2013-08-16
            copyright            : (C) 2013 by Walter Tsui
            email                : waltertech426@gmail.com
         ***************************************************************************/
        /***************************************************************************
         *                                                                         *
         *   This program is free software; you can redistribute it and/or modify  *
         *   it under the terms of the GNU General Public License as published by  *
         *   the Free Software Foundation; either version 2 of the License, or     *
         *   (at your option) any later version.                                   *
         *                                                                         *
         ***************************************************************************/
            %}
            
            %compute the distance between the signal and its boundary,
            %coordinate of the decision boundary intersected with the line crossing the
            %2 Signal Symbols
            %and other parameters for the decision boundary line
            
            %s1: Signal Symbol 1
            %s2: Signal Symbol 2
            %prob_1: Probability of Signal Symbol 1
            %prob_2: Probability of Signal Symbol 2
            %No: noise power spectral density
            
            % Bdry_Y_Intcp: y-intercept of the boundary line
            
            dim = length(s1);
            
            Bdry_Coor = double.empty;
            
            if(prob_1 == 0 || prob_2 == 0)
                for i = 1:dim
                    Bdry_Coor(i) = 0;
                end
                
                Bdry_Y_Intcp = 0;
                Bdry_Slope = 0;
                
                return;
                
            end
            
            
            %Calculate the distance, d, between s1 and s2
            d = 0;
            
            for i = 1:dim
                d = d + (s2(i) - s1(i))^2;
            end
            
            d = sqrt(d);
            
            
            %BdryDistFrmS1 is the distance of boundary line away from symbol 1
            %Decision Regions Equation, u = (No/(2*d))*log(prob_1/prob_2) + d/2;
            BdryDistFrmS1 = (No/(2*d))*log(prob_1/prob_2) + d/2;
            
            %Calculate the coordinate at the boundary at which S1 and S2 are orthogonal to the boundary
            for i = 1:dim
                Bdry_Coor(i) = BdryDistFrmS1*(s2(i) - s1(i))/d + s1(i);
            end
            
            %Calculate the boundary slope and y-intercept
            %for i = 1:dim
            if((s2(1) - s1(1)) == 0)
                Bdry_Slope = 0;
                Bdry_Y_Intcp = Bdry_Coor(2);
                
            else
                if((s2(2) - s1(2)) == 0)
                    Bdry_Slope = Inf;
                    Bdry_Y_Intcp = Bdry_Coor(1);
                    
                else
                    Bdry_Slope = (s2(2) - s1(2))/(s2(1) - s1(1));
                    Bdry_Slope = -(1/Bdry_Slope);
                    Bdry_Y_Intcp = Bdry_Coor(2) - Bdry_Slope*Bdry_Coor(1);
                    
                end
                
            end
            %end
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        % Analyzing the scatterplots, drawing histogram, and finding
        % the probability of error, maximum error, and estimated
        % thickness
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function analyze_constellation_results(app)
            
            % Drawing Histogram on axes 2
            app.h = double.empty;
            for i = 1:1:size(app.scattered_values, 2)
                % causing error
                app.h(i) = app.minimum_euclidean_distance(transpose(app.scattered_values(:, i)), app.frequency);
                % use this to calcaulate max error
                
            end
            app.s = histogram(app.UIAxes2, app.h);
            grid(app.UIAxes2, 'on');
            app.UIAxes2.XLim = [app.tmin , app.tmax+1];
            
            
            app.analyze_histogram();
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Histogram anaylsios to find prob of error, thickness estimated,
        % ...
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function analyze_histogram(app)
            % Maximum Error & Probability of error caclulations
            
            % Error Calculation
            BinCenters(1) = app.s.BinLimits(1) + app.s.BinWidth/2;                  % Find the thickness at the which the farthest bin to the left from the actual thickness is plotted
            BinCenters(2) = app.s.BinLimits(2) - app.s.BinWidth/2;                  % Find the thickness at the which the farthest bin to the right from the actual thickness is plotted
            distance = abs(app.desired_thickness - BinCenters);             % Find the deveation from the actual value
            [app.MaximumError, ~] = max(distance);                          % The error represents the value with maximum deveation from the actual value
            app.MaximumErrorEditField.Value = app.MaximumError;
            
            
            % Probability of error
            hist_thickness = app.s.BinEdges - app.s.BinWidth/2;                                         % Find the centers of the bins created which represent each thickness
            hist_thickness(1) = [];                                                             % Delete the first element since it represents nothing ( first binEdge-binWidth/2)
            
            % find the number of correctly estimated thicknesses according
            % to the error range given by user
            Number_of_correctly_estimated_thickness = 0;
            for i = app.desired_thickness - app.error_range : app.thickness_step : app.desired_thickness + app.error_range
                Number_of_correctly_estimated_thickness = Number_of_correctly_estimated_thickness + sum(app.h(:) == i);
            end
            
            %length(app.scattered_Values)
            app.probability_of_error = (length(app.h) - Number_of_correctly_estimated_thickness)/length(app.h);           % divide wrong estimations by total estimations
            app.ProbabilityoferrorEditField.Value = app.probability_of_error;
            
            
            % Estimated thickness
            [~, indexOfEstimatedThickness] = max(app.s.Values);
            app.Thickness_calculated = hist_thickness(indexOfEstimatedThickness);
            app.ThicknessEstimated.Value = app.Thickness_calculated;
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Updates front-end based on teh functionailty selected
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function UpdateLayouts(app)
            
            if app.functionality == "Simulate Spillage"
                
                app.UpdateSpillagePage();
                
            elseif app.functionality == "Bayesian Statistics"
                
                app.UpdateBayesianPage();
                
            elseif app.functionality == "Constellations"
                app.UpdateConstellationsPage();
            else
                app.UpdateExperimemtalDataPage();
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Updates the Spillage Page accodring to the required plots
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function UpdateSpillagePage(app)
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            title(app.UIAxes, "Spillage");
            title(app.UIAxes2, "Detection");
            title(app.UIAxes3, "Estimation");
            app.UIAxes.Position = [55,406,391,284];
            app.UIAxes2.Position = [29,119,316,258];
            app.UIAxes3.Position = [368,116,318,261];
            app.UIAxes.Visible = 'on';
            app.UIAxes2.Visible = "on";
            app.UIAxes3.Visible = "on";
            
            app.SpillButton.Visible = "on";
            app.AdditionalPlotsPanel.Visible = "on";
            app.DesiredthicknessEditField.Visible = "off";
            app.DesiredthicknessEditFieldLabel.Visible = "off";
            app.ShowhistogramButton.Visible = "on";
            app.ObservationsMSpinner.Visible = "on";
            app.PlotsDropDown.Visible = "off";
            app.PlotsDropDownLabel.Visible = "off";
            app.PlotButton.Visible = "off";
            app.ClearfiguresButton.Visible = "on";
            app.CalculateButton.Visible = "on";
            app.NoisePropertiesPanel.Visible = "on";
            app.ShowGridLinesCheckBox.Visible= "off";
            app.ProbabilityoferrorEditField.Visible = 'off';
            app.ProbabilityoferrorEditFieldLabel.Visible = 'off';
            app.MaximumErrorEditField.Visible = 'off';
            app.MaximumErrorEditFieldLabel.Visible = 'off';
            app.ThicknessEstimated.Visible = 'off';
            app.ThicknessEstimatedLabel.Visible = 'off';
            app.mmSpinner.Visible = "off";
            app.mmSpinnerLabel.Visible = 'off';
            app.ObservationsMLabel.Visible = 'on';
            app.ObservationsMSpinner.Visible = 'on';
            app.Jointpdf.Visible = 'on';
            app.Jointpdf.Position = [42,83,67,22];
            app.Default.Visible = 'on';
            app.Default.Position = [42,47,113,22];
            
            app.ExtractFigureButton.Visible = 'on';
            app.ExtractFigureButton_2.Visible = 'on';
            app.ExtractFigureButton_3.Visible = 'on';
            
            app.ExtractFigureButton.Position = [346,375,100,22];
            app.ExtractFigureButton_2.Position = [247,83,100,22];
            app.ExtractFigureButton_3.Position = [578,84,100,22];
            
            app.ClearfiguresButton.Position = [514,645,100,22];
            app.ShowGridLinesCheckBox.Position = [83,645,111,23];
            
            app.TestingDetailsPanel.Visible = 'off';
            app.ProbabilityOfOilDetectionEditField.Visible = 'off';
            app.ProbabilityOfOilDetectionEditFieldLabel.Visible = 'off';
            
            app.CalculateButton.Enable = 1;
            
            app.samples = int64(app.SamplesSlider.Value);
            
            app.DetectorErrorPercentageEditField.Visible = "on";
            app.DetectorErrorPercentageEditFieldLabel.Visible = "on";
            app.EstimatorErrorPercentageEditField.Visible = "on";
            app.EstimatorErrorPercentageEditFieldLabel.Visible = "on";
            app.EstimationerrormarginSpinner.Visible = "on";
            app.EstimationerrormarginSpinnerLabel.Visible = "on";
            
            if app.Jointpdf.Value == 1
                app.SamplesSlider.Enable = 0;
            else
                app.SamplesSlider.Enable = 1;
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        % Updates the Bayesian Page accodring to the required plots
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function UpdateBayesianPage(app)
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.UIAxes2.Visible = "off";
            app.UIAxes3.Visible = "off";
            title(app.UIAxes, "Title");
            app.UIAxes.Position = [62,142,581,468];
            app.UIAxes.XLim = [app.tmin, app.tmax];
            app.SpillButton.Visible = "off";
            app.AdditionalPlotsPanel.Visible = "off";
            app.ShowhistogramButton.Visible = "off";
            app.ObservationsMSpinner.Visible = "on";
            app.PlotsDropDown.Visible = "on";
            app.PlotsDropDownLabel.Visible = "on";
            app.PlotButton.Visible = "on";
            app.ClearfiguresButton.Visible = "on";
            app.CalculateButton.Visible = "off";
            
            app.ShowGridLinesCheckBox.Visible = "on";
            
            app.ProbabilityoferrorEditField.Visible = 'off';
            app.ProbabilityoferrorEditFieldLabel.Visible = 'off';
            app.MaximumErrorEditField.Visible = 'off';
            app.MaximumErrorEditFieldLabel.Visible = 'off';
            app.ThicknessEstimated.Visible = 'off';
            app.ThicknessEstimatedLabel.Visible = 'off';
            app.mmSpinner.Visible = "off";
            app.mmSpinnerLabel.Visible = 'off';
            app.Jointpdf.Visible = 'off';
            app.Default.Visible = 'off';
            
            app.ExtractFigureButton.Visible = 'on';
            app.ExtractFigureButton_2.Visible = 'off';
            app.ExtractFigureButton_3.Visible = 'off';
            
            app.ExtractFigureButton.Position = [83,610,100,22];
            
            app.SamplesSlider.Enable = 1;
            app.SamplesDisplayEditField.Value = 1000;
            app.SamplesSlider.Value = 1000;
            
            app.TestingDetailsPanel.Visible = 'off';
            app.CalculateButton.Enable = 1;
            
            app.DesiredthicknessEditField.Visible = "off";
            app.DesiredthicknessEditFieldLabel.Visible = "off";
            
            app.DetectorErrorPercentageEditField.Visible = "off";
            app.DetectorErrorPercentageEditFieldLabel.Visible = "off";
            app.EstimatorErrorPercentageEditField.Visible = "off";
            app.EstimatorErrorPercentageEditFieldLabel.Visible = "off";
            app.EstimationerrormarginSpinner.Visible = "off";
            app.EstimationerrormarginSpinnerLabel.Visible = "off";
            
            if app.PlotsDropDown.Value == "Reflectivity vs. thickness"
                app.NoisePropertiesPanel.Visible = "off";
                title(app.UIAxes, "Reflectivity vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Coherent Reflectivity (dB)";
                app.ObservationsMLabel.Visible = 'off';
                app.ObservationsMSpinner.Visible = 'off';
                cla(app.UIAxes);
            else
                app.NoisePropertiesPanel.Visible = "on";
                title(app.UIAxes, "Probability of detection vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Probability of detection";
                app.ObservationsMLabel.Visible = 'on';
                app.ObservationsMSpinner.Visible = 'on';
                
                cla(app.UIAxes);
            end
            
            app.ReflectivityLegends = string.empty;
            app.ProbabilityLegends = string.empty;
            app.Jointpdflegends = string.empty;
            
            app.PlotButton.Position = [514,635,100,22];
            app.ClearfiguresButton.Position = [514,600,100,22];
            app.ShowGridLinesCheckBox.Position = [83,645,111,23];
            app.ProbabilityOfOilDetectionEditField.Visible = 'off';
            app.ProbabilityOfOilDetectionEditFieldLabel.Visible = 'off';
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Updating the front end of the constellations page according to
        % the number of frequencies and thus according to the required
        % graphs to be shown
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function UpdateConstellationsPage(app)
            app.SpillButton.Visible = "off";
            app.AdditionalPlotsPanel.Visible = "off";
            app.DesiredthicknessEditField.Visible = "on";
            app.DesiredthicknessEditFieldLabel.Visible = "on";
            app.CalculateButton.Visible = 'on';
            app.PlotsDropDown.Visible = 'off';
            app.PlotsDropDownLabel.Visible = 'off';
            app.PlotButton.Visible = 'off';
            app.ClearfiguresButton.Visible = 'off';
            app.NoisePropertiesPanel.Visible = 'on';
            app.ShowGridLinesCheckBox.Visible = 'off';
            app.ShowhistogramButton.Visible = 'off';
            
            app.ProbabilityoferrorEditField.Visible = 'on';
            app.ProbabilityoferrorEditFieldLabel.Visible = 'on';
            app.MaximumErrorEditField.Visible = 'on';
            app.MaximumErrorEditFieldLabel.Visible = 'on';
            app.ThicknessEstimated.Visible = 'on';
            app.ThicknessEstimatedLabel.Visible = 'on';
            app.mmSpinner.Visible = "on";
            app.mmSpinnerLabel.Visible = 'on';
            app.ObservationsMLabel.Visible = 'on';
            app.ObservationsMSpinner.Visible = 'on';
            app.Jointpdf.Visible = 'off';
            app.Default.Visible = 'off';
            
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            
            title(app.UIAxes, "Title");
            
            app.ShowGridLinesCheckBox.Position = [83,645,111,23];
            
            app.TestingDetailsPanel.Visible = 'off';
            
            app.desired_thickness = app.DesiredthicknessEditField.Value;
            app.CalculateButton.Enable = 1;
            
            app.SamplesSlider.Enable = 1;
            app.samples = app.SamplesSlider.Value;
            
            app.DetectorErrorPercentageEditField.Visible = "off";
            app.DetectorErrorPercentageEditFieldLabel.Visible = "off";
            app.EstimatorErrorPercentageEditField.Visible = "off";
            app.EstimatorErrorPercentageEditFieldLabel.Visible = "off";
            app.EstimationerrormarginSpinner.Visible = "off";
            app.EstimationerrormarginSpinnerLabel.Visible = "off";
            
            if length(app.frequency) == 2 || length(app.frequency) == 3
                
                % scatterplots and histogram will be shown
                
                app.UIAxes.Position = [70,421,418,292];
                app.UIAxes2.Position = [29,119,316,258];
                app.UIAxes.Visible = 'on';
                app.UIAxes2.Visible = 'on';
                app.UIAxes3.Visible = 'off';
                
                app.ThicknessEstimated.Position = [530,291,100,22];
                app.ThicknessEstimatedLabel.Position =[398,291,117,22];
                app.ProbabilityoferrorEditField.Position = [530,244,100,22];
                app.ProbabilityoferrorEditFieldLabel.Position = [412,244,103,22];
                app.mmSpinner.Position = [530,205,50,22];
                app.mmSpinnerLabel.Position = [464,205,51,22];
                app.MaximumErrorEditField.Position = [530,162,100,22];
                app.MaximumErrorEditFieldLabel.Position = [429,161,87,22];
                
                app.ExtractFigureButton.Visible = 'on';
                app.ExtractFigureButton_2.Visible = 'on';
                app.ExtractFigureButton_3.Visible = 'off';
                app.ExtractFigureButton.Position = [366,385,100,22];
                app.ExtractFigureButton_2.Position = [40, 90, 100,22];
                
            else
                
                % histogram only will be shown, since 4D and more can't be
                % visulaised, and 1D is useless
                
                app.UIAxes2.Position = [69,226,560,456];
                app.UIAxes.Visible = 'off';
                app.UIAxes2.Visible = 'on';
                app.UIAxes3.Visible = 'off';
                
                app.ThicknessEstimated.Position = [529,175,100,22];
                app.ThicknessEstimatedLabel.Position =[397,175,117,22];
                app.ProbabilityoferrorEditField.Position = [529,128,100,22];
                app.ProbabilityoferrorEditFieldLabel.Position = [411,128,103,22];
                app.mmSpinner.Position = [529, 81, 50, 22];
                app.mmSpinnerLabel.Position = [464, 81, 51, 22];
                app.mmSpinner.Step = app.thickness_step;
                app.MaximumErrorEditField.Position = [529, 34, 100, 22];
                app.MaximumErrorEditFieldLabel.Position = [429, 34, 87, 22];
                
                app.ExtractFigureButton.Visible = 'off';
                app.ExtractFigureButton_2.Visible = 'on';
                app.ExtractFigureButton_3.Visible = 'off';
                app.ExtractFigureButton_2.Position = [65, 175, 100,22];
                
            end
            
            app.ClearfiguresButton.Position = [514,645,100,22];
            app.ProbabilityOfOilDetectionEditField.Visible = 'off';
            app.ProbabilityOfOilDetectionEditFieldLabel.Visible = 'off';
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        % Updating experimental result page
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function UpdateExperimemtalDataPage(app)
            app.PlotButton.Visible = "off";
            app.PlotsDropDown.Visible = "off";
            app.PlotsDropDownLabel.Visible = "off";
            app.ShowGridLinesCheckBox.Position = [83,700,111,23];
            app.SpillButton.Visible = "off";
            app.NoisePropertiesPanel.Visible = "off";
            app.AdditionalPlotsPanel.Visible = "off";
            
            app.UIAxes3.Visible = "off";
            app.ExtractFigureButton_3.Visible = "off";
            app.ShowhistogramButton.Visible = "off";
            
            cla(app.UIAxes, 'reset');
            app.UIAxes.Visible = "on";
            app.UIAxes.Position = [40,380,391,284];
            app.ExtractFigureButton.Position = [560,430,100,22];
            app.ThicknessEstimated.Visible ="on";
            app.ThicknessEstimatedLabel.Visible = "on";
            app.ThicknessEstimated.Position = [560,604,100,22];
            app.ThicknessEstimatedLabel.Position = [430,604,117,22];
            app.ProbabilityoferrorEditField.Visible = "on";
            app.ProbabilityoferrorEditFieldLabel.Visible = "on";
            app.ProbabilityoferrorEditField.Position = [560,565,100,22];
            app.ProbabilityoferrorEditFieldLabel.Position = [430,565,103,22];
            app.mmSpinner.Visible = "on";
            app.mmSpinnerLabel.Visible = "on";
            app.mmSpinner.Position = [560,526,100,22];
            app.mmSpinnerLabel.Position = [480,526,51,22];
            app.MaximumErrorEditField.Visible = "on";
            app.MaximumErrorEditFieldLabel.Visible = "on";
            app.MaximumErrorEditField.Position = [560,483,100,22];
            app.MaximumErrorEditFieldLabel.Position = [430,483,87,22];
            
            cla(app.UIAxes2, 'reset');
            app.UIAxes2.Visible = "off";
            app.ExtractFigureButton_2.Visible = 'off';
            
            cla(app.UIAxes3, 'reset');
            app.UIAxes3.Visible = "off";
            
            app.TestingDetailsPanel.Visible = 'on';
            
            app.ClearfiguresButton.Position = [560,320,100,22];
            app.CalculateButton.Visible = 'on';
            
            app.desired_thickness = app.ThicknessEditField.Value;
            app.ProbabilityOfOilDetectionEditField.Visible = 'on';
            app.ProbabilityOfOilDetectionEditFieldLabel.Visible = 'on';
            
            
            app.Jointpdf.Visible = 'off';
            app.Default.Visible = 'off';
            
            app.ProbabilityOfOilDetectionEditField.Position = [222,322,100,22];
            app.ProbabilityOfOilDetectionEditFieldLabel.Position = [55,322,150,22];
            
            app.DetectorErrorPercentageEditField.Visible = "off";
            app.DetectorErrorPercentageEditFieldLabel.Visible = "off";
            app.EstimatorErrorPercentageEditField.Visible = "off";
            app.EstimatorErrorPercentageEditFieldLabel.Visible = "off";
            app.EstimationerrormarginSpinner.Visible = "off";
            app.EstimationerrormarginSpinnerLabel.Visible = "off";
            
            if  isempty(app.Experimental_results_path) || app.CalculateButton.Enable == 0
                app.CalculateButton.Enable = 0;
            else
                app.CalculateButton.Enable = 1;
            end
            
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.frequency= str2double(strsplit(app.FrequenciesGHzEditField.Value,','));
            app.M = app.ObservationsMSpinner.Value;
            app.variance = app.VarianceEditField.Value;
            app.samples = app.SamplesSlider.Value;
            app.ks = app.RoughnessEditField.Value;
            app.E_air = app.AirdielectricEditField.Value;
            app.E_oil = app.OildielectricEditField.Value;
            app.temp = app.WaterTempertatureCEditField.Value;
            app.salinity = app.WaterSalinitypptEditField.Value;
            app.theta = app.IncidentangledegreeEditField.Value;
            app.thickness_step = app.ThicknessstepmmEditField.Value;
            app.tmin = app.tminEditField.Value;
            app.tmax = app.tmaxEditField.Value;
            app.options = app.OptionsDropDown.Value;
            app.functionality = app.FunctionalityDropDown.Value;
            app.desired_thickness = app.DesiredthicknessEditField.Value;
            app.error_range = app.mmSpinner.Value;
            app.DesiredthicknessEditField.Visible = "off";
            app.DesiredthicknessEditFieldLabel.Visible = "off";
            title(app.UIAxes, "Fractal Surface");
            title(app.UIAxes2, "Detection");
            title(app.UIAxes3, "Estimation");
            app.ClearfiguresButton.Visible = "on";
            app.PlotsDropDown.Visible = "off";
            app.PlotsDropDownLabel.Visible = "off";
            app.PlotButton.Visible = "off";
            app.ShowGridLinesCheckBox.Visible= "off";
            app.ProbabilityoferrorEditField.Visible = 'off';
            app.ProbabilityoferrorEditFieldLabel.Visible = 'off';
            app.MaximumErrorEditField.Visible = 'off';
            app.MaximumErrorEditFieldLabel.Visible = 'off';
            app.ThicknessEstimated.Visible = 'off';
            app.ThicknessEstimatedLabel.Visible = 'off';
            app.mmSpinner.Visible = "off";
            app.mmSpinnerLabel.Visible = 'off';
            app.SamplesDisplayEditField.Value = app.samples;
            app.Defaultdetection = app.DefaultdetectionCheckBox.Value;
            app.Jointpdfdetection = app.JointpdfdetectionCheckBox.Value;
            app.pO = 0.5;
            app.pW = 0.5;
            app.TestingDetailsPanel.Visible = 'off';
            app.ProbabilityOfOilDetectionEditField.Visible = 'off';
            app.ProbabilityOfOilDetectionEditFieldLabel.Visible = 'off';
            app.ClearfiguresButton.Position = [514,645,100,22];
            app.SamplesSlider.Enable = 0;
        end

        % Button pushed function: 
        % GenerateforallfrequencytriadsButton
        function GenerateforallfrequencytriadsButtonPushed(app, event)
            if app.options == "Probability of detection"
                app.Detect_for_triads;
            else
                app.Estimate_for_triads;
            end
        end

        % Button pushed function: GenerateforallfrequencypairsButton
        function GenerateforallfrequencypairsButtonPushed(app, event)
            if app.options == "Probability of detection"
                app.Detect_for_pairs;
            elseif app.options == "Thickness Estimation"
                app.Estimate_for_pairs;
            else
                app.Detect_for_pairs;
                app.Estimate_for_pairs;
                
            end
        end

        % Button pushed function: SpillButton
        function SpillButtonPushed(app, event)
            app.n = 2;        % image resolution will be (2^n + 1)
            app.H = 0.7;
            app.G = app.fractalsurface(app.n, app.H);
            app.G = double(int64(normalize(app.G, 'range', [app.tmin app.tmax])));
            spill_clims = [app.tmin app.tmax];
            imagesc(app.UIAxes, app.G, spill_clims);
            axis(app.UIAxes, 'tight');
            colormap(app.UIAxes, 'jet');
            colorbar(app.UIAxes);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            if app.functionality == "Simulate Spillage"
                
                app.Calculate_button_pushed = 1;
                measured_reflectivities = double.empty;
                
                %generate reflectivity values
                for i =1:1:length(app.frequency)
                    measured_reflectivities(:, :, i) = app.reflectivity(app.frequency(i), app.G);
                end
                
                % calculate the probability of detection
                y = app.Detect(measured_reflectivities, app.frequency);
                clims = [0, 1];
                imagesc(app.UIAxes2, y, clims);
                axis(app.UIAxes2, 'tight');
                title(app.UIAxes2, 'Detection');
                cmp = gray;
                cmp = flipud(cmp);
                colormap(app.UIAxes2, cmp);
                colorbar(app.UIAxes2)
                
                % estimate thicknesses
                app.Estimated_thickness = app.Estimate_Thickness(measured_reflectivities, app.frequency);
                clims = [app.tmin, app.tmax];
                imagesc(app.UIAxes3, app.Estimated_thickness, clims);
                axis(app.UIAxes3, 'tight');
                title(app.UIAxes3, 'Estimation');
                colormap(app.UIAxes3, 'jet');
                colorbar(app.UIAxes3);
                
                app.Calculate_button_pushed = 0;
                
            elseif app.functionality == "Constellations"
                
                app.UpdateConstellationsPage();
                app.scatter_points();
                app.analyze_constellation_results();
                
            else
                
                file = cd(app.Experimental_results_path);
                
                file_name = append('Reflectivities', num2str(app.ThicknessEditField.Value), '.mat');
                
                try
                    load(file_name);
                catch
                    warndlg("Wrong file format/name")
                end
                
                index = double.empty;
                
                for i = 1:1:length(app.frequency)
                    [~, index(i)] = min(abs((f*10^(-9))-app.frequency(i)));
                end
                
                measured_reflectivities = double.empty;
                
                for i = 1:length(app.frequency)
                    measured_reflectivities(:, :, i) = 10.^(Reflectivities(randperm(size(Reflectivities, 1), app.M), index(i))/10);
                    
                end
                
                app.Experimental_thickness_estimation(measured_reflectivities);
                app.Detect_for_experimental_results(measured_reflectivities);
                
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {754, 754};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {609, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ShowhistogramButton
        function ShowhistogramButtonPushed(app, event)
            clf;
            histogram(app.Estimated_thickness);
        end

        % Value changed function: FrequenciesGHzEditField
        function FrequenciesGHzEditFieldValueChanged(app, event)
            app.frequency= str2double(strsplit(app.FrequenciesGHzEditField.Value,','));
        end

        % Value changed function: ObservationsMSpinner
        function ObservationsMSpinnerValueChanged(app, event)
            app.M = app.ObservationsMSpinner.Value;
        end

        % Value changed function: VarianceEditField
        function VarianceEditFieldValueChanged(app, event)
            app.variance = app.VarianceEditField.Value;
        end

        % Value changed function: SamplesSlider
        function SamplesSliderValueChanged(app, event)
            app.samples = int64(app.SamplesSlider.Value);
            app.SamplesDisplayEditField.Value = double(app.samples);
        end

        % Value changed function: RoughnessEditField
        function RoughnessEditFieldValueChanged(app, event)
            app.ks = app.RoughnessEditField.Value;
        end

        % Value changed function: AirdielectricEditField
        function AirdielectricEditFieldValueChanged(app, event)
            app.E_air = app.AirdielectricEditField.Value;
        end

        % Value changed function: OildielectricEditField
        function OildielectricEditFieldValueChanged(app, event)
            app.E_oil = app.OildielectricEditField.Value;
        end

        % Value changed function: WaterTempertatureCEditField
        function WaterTempertatureCEditFieldValueChanged(app, event)
            app.temp = app.WaterTempertatureCEditField.Value;
        end

        % Value changed function: WaterSalinitypptEditField
        function WaterSalinitypptEditFieldValueChanged(app, event)
            app.salinity = app.WaterSalinitypptEditField.Value;
        end

        % Value changed function: IncidentangledegreeEditField
        function IncidentangledegreeEditFieldValueChanged(app, event)
            app.theta = app.IncidentangledegreeEditField.Value;
        end

        % Value changed function: ThicknessstepmmEditField
        function ThicknessstepmmEditFieldValueChanged(app, event)
            app.thickness_step = app.ThicknessstepmmEditField.Value;
        end

        % Value changed function: tminEditField
        function tminEditFieldValueChanged(app, event)
            app.tmin = app.tminEditField.Value;
        end

        % Value changed function: tmaxEditField
        function tmaxEditFieldValueChanged(app, event)
            app.tmax = app.tmaxEditField.Value;
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            app.options = app.OptionsDropDown.Value;
            if app.OptionsDropDown.Value == "Probability of detection"
                app.JointpdfdetectionCheckBox.Visible = 'on';
                app.DefaultdetectionCheckBox.Visible = 'on';
            else
                app.JointpdfdetectionCheckBox.Visible = 'off';
                app.DefaultdetectionCheckBox.Visible = 'off';
            end
        end

        % Value changed function: FunctionalityDropDown
        function FunctionalityDropDownValueChanged(app, event)
            
            app.functionality = app.FunctionalityDropDown.Value;
            app.UpdateLayouts();
            
            if app.functionality == "Bayesian Statistics"
                app.SamplesSlider.Limits = [100, 5000];
            elseif app.functionality == "Experimental data"
                app.ThicknessEstimated.Value = 0;
                app.ProbabilityoferrorEditField.Value = 0;
                app.mmSpinner.Value = 0;
                app.MaximumErrorEditField.Value = 0;
            else
                app.SamplesSlider.Limits = [1, 5000];
                app.SamplesSlider.Value = 1;
                app.SamplesDisplayEditField.Value = app.SamplesSlider.Value;
            end
        end

        % Value changed function: DesiredthicknessEditField
        function DesiredthicknessEditFieldValueChanged(app, event)
            app.desired_thickness = app.DesiredthicknessEditField.Value;
        end

        % Value changed function: PlotsDropDown
        function PlotsDropDownValueChanged(app, event)
            
            if app.PlotsDropDown.Value == "Reflectivity vs. thickness"
                
                app.NoisePropertiesPanel.Visible = "off";
                title(app.UIAxes, "Reflectivity vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Coherent Reflectivity (dB)";
                app.ObservationsMLabel.Visible = 'off';
                app.ObservationsMSpinner.Visible = 'off';
                app.SamplesSlider.Visible = 'off';
                app.SamplesSliderLabel.Visible = 'off';
                
                cla(app.UIAxes);
                
            elseif app.PlotsDropDown.Value == "Probability of detection vs. thickness"
                
                app.SamplesSlider.Limits = [100, 5000];
                app.NoisePropertiesPanel.Visible = "on";
                title(app.UIAxes, "Probability of detection vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Probability of detection";
                app.ObservationsMLabel.Visible = 'on';
                app.ObservationsMSpinner.Visible = 'on';
                
                cla(app.UIAxes);
                
            else
                
                title(app.UIAxes, "Probability of detection with joint pdf vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Probability of detection with joint pdf";
                app.ObservationsMLabel.Visible = 'on';
                app.ObservationsMSpinner.Visible = 'on';
                app.NoisePropertiesPanel.Visible = 'on';
                app.SamplesSlider.Visible = 'on';
                app.SamplesSliderLabel.Visible = 'on';
                cla(app.UIAxes);
                
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            thickness_range = app.tmin:app.thickness_step:app.tmax;
            
            if app.PlotsDropDown.Value == "Reflectivity vs. thickness"
                
                R = 10*log10(app.reflectivity(app.frequency, thickness_range));
                plot(app.UIAxes, thickness_range, R);
                hold(app.UIAxes, "on");
                title(app.UIAxes, "Reflectivity vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Coherent Reflectivity (dB)";
                
                reflectivity_legend_strings = app.ReflectivityLegends;
                
                for i = 1:1:length(app.frequency)
                    x = string(strcat("f = ", num2str(app.frequency(i)), " GHz, ks = ", num2str(app.ks), ", \epsilon_{oil} = ", num2str(app.E_oil)));
                    reflectivity_legend_strings(length(reflectivity_legend_strings) + 1, 1) = x ;
                end
                
                legend(app.UIAxes, reflectivity_legend_strings, 'Box',"off");
                app.ReflectivityLegends = reflectivity_legend_strings;
                
            elseif app.PlotsDropDown.Value == "Probability of detection vs. thickness"
                
                app.samples = int64(app.SamplesSlider.Value);
                probability = app.probability_of_detection();
                plot(app.UIAxes, thickness_range, probability);
                
                probability_legend_strings = app.ProbabilityLegends;
                
                frequency_string = sprintf('%.0f,' , app.frequency);
                frequency_string = frequency_string(1:end-1);
                
                x = string(strcat("f = ", frequency_string, " GHz, ks = ", num2str(app.ks), ", \epsilon_{oil} = ", num2str(app.E_oil)));
                probability_legend_strings(length(probability_legend_strings) + 1, 1) = x ;
                
                app.ProbabilityLegends = probability_legend_strings;
                legend(app.UIAxes, probability_legend_strings, 'Box',"off");
                
                hold(app.UIAxes, "on");
                
            else
                
                app.samples = int64(app.SamplesSlider.Value);
                joint_legend_strings = app.Jointpdflegends;  
                
                prob = double.empty;
                x_values = app.tmin:app.thickness_step:app.tmax;
                
                for i = app.tmin:app.thickness_step:app.tmax
                    oil = 0;
                    for j = 0:app.samples
                        desired_reflectivity  = app.reflectivity(app.frequency, i);
                        
                        oil_found = app.Detect_with_joint_pdf_single_point(desired_reflectivity, app.frequency);
                        
                        if oil_found >= 1
                            oil = oil + 1;
                        end
                    end
                    prob(length(prob) + 1) = oil/double(app.samples);
                end
                plot(app.UIAxes, x_values, prob);
                
                frequency_string = sprintf('%.0f,' , app.frequency);
                frequency_string = frequency_string(1:end-1);
                
                x = string(strcat("f = ", frequency_string, " GHz, ks = ", num2str(app.ks), ", \epsilon_{oil} = ", num2str(app.E_oil)));
                joint_legend_strings(length(joint_legend_strings) + 1, 1) = x ;
                
                app.Jointpdflegends = joint_legend_strings;s
                legend(app.UIAxes, joint_legend_strings, 'Box',"off");
                
                hold(app.UIAxes, "on");
                
            end
     
        end

        % Button pushed function: ClearfiguresButton
        function ClearfiguresButtonPushed(app, event)
        cla(app.UIAxes);
        cla(app.UIAxes2);
        cla(app.UIAxes3);
        app.ReflectivityLegends = string.empty;
        app.ProbabilityLegends = string.empty;
        app.Jointpdflegends = string.empty;
        end

        % Value changed function: ShowGridLinesCheckBox
        function ShowGridLinesCheckBoxValueChanged(app, event)
        if app.ShowGridLinesCheckBox.Value == 1
            
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.ZGrid = 'on';
            
            app.UIAxes2.XGrid = 'on';
            app.UIAxes2.YGrid = 'on';
            app.UIAxes2.ZGrid = 'on';
            
            app.UIAxes3.XGrid = 'on';
            app.UIAxes3.YGrid = 'on';
            app.UIAxes3.ZGrid = 'on';
            
        else
            
            app.UIAxes.XGrid = 'off';
            app.UIAxes.YGrid = 'off';
            app.UIAxes.ZGrid = 'off';
            
            app.UIAxes2.XGrid = 'off';
            app.UIAxes2.YGrid = 'off';
            app.UIAxes2.ZGrid = 'off';
            
            app.UIAxes3.XGrid = 'off';
            app.UIAxes3.YGrid = 'off';
            app.UIAxes3.ZGrid = 'off';
        end
        
        end

        % Value changed function: mmSpinner
        function mmSpinnerValueChanged(app, event)
        app.mmSpinner.Step = app.thickness_step;
        app.error_range = app.mmSpinner.Value;
        app.analyze_histogram();
        end

        % Value changed function: Jointpdf
        function JointpdfValueChanged(app, event)
        app.Default.Value = 1-app.Jointpdf.Value;
        app.SamplesSlider.Enable = 0;
        
        if app.FunctionalityDropDown == "Simulate Spillage"
            y = app.Detect(app.reflectivities, app.frequency);
            clims = [0, 1];
            imagesc(app.UIAxes2, y, clims);
            axis(app.UIAxes2, 'tight');
            title(app.UIAxes2, 'Detection');
            cmp = gray;
            cmp = flipud(cmp);
            colormap(app.UIAxes2, cmp);
            colorbar(app.UIAxes2)
        end
        
        end

        % Value changed function: Default
        function DefaultValueChanged(app, event)
        app.Jointpdf.Value = 1 - app.Default.Value;
        app.SamplesSlider.Enable = 1;
        
        if app.FunctionalityDropDown == "Simulate Spillage"
            y = app.Detect(app.reflectivities, app.frequency);
            clims = [0, 1];
            imagesc(app.UIAxes2, y, clims);
            axis(app.UIAxes2, 'tight');
            title(app.UIAxes2, 'Detection');
            cmp = gray;
            cmp = flipud(cmp);
            colormap(app.UIAxes2, cmp);
            colorbar(app.UIAxes2);
            
        end
        end

        % Value changed function: JointpdfdetectionCheckBox
        function JointpdfdetectionCheckBoxValueChanged(app, event)
        app.Jointpdfdetection = app.JointpdfdetectionCheckBox.Value;
        app.DefaultdetectionCheckBox.Value = 1-app.JointpdfdetectionCheckBox.Value;
        app.Defaultdetection = 1 - app.Jointpdfdetection;
        end

        % Value changed function: DefaultdetectionCheckBox
        function DefaultdetectionCheckBoxValueChanged(app, event)
        app.Defaultdetection = app.DefaultdetectionCheckBox.Value;
        app.JointpdfdetectionCheckBox.Value = 1 - app.DefaultdetectionCheckBox.Value;
        app.Jointpdfdetection = 1 - app.Defaultdetection;
        
        end

        % Button pushed function: ExtractFigureButton
        function ExtractFigureButtonPushed(app, event)
        copyUIAxes(app.UIAxes, figure);
        end

        % Button pushed function: ExtractFigureButton_2
        function ExtractFigureButton_2Pushed(app, event)
        copyUIAxes(app.UIAxes2, figure);
        end

        % Button pushed function: ExtractFigureButton_3
        function ExtractFigureButton_3Pushed(app, event)
        copyUIAxes(app.UIAxes3, figure);
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
        app.desired_thickness = app.ThicknessEditField.Value;
        end

        % Button pushed function: ChangeDataFolderButton
        function ChangeDataFolderButtonPushed(app, event)
        app.Experimental_results_path = uigetdir;
        
        try
            app.DirectoryEditField.Value = app.Experimental_results_path;
        catch
            warndlg( 'File format not valid. Choose another file.' , "Error" )
        end
        
        if app.Experimental_results_path == 0
            app.CalculateButton.Enable = 0;
        else
            app.CalculateButton.Enable = 1;
        end
        
        app.UpdateExperimemtalDataPage();
        end

        % Value changed function: EstimationerrormarginSpinner
        function EstimationerrormarginSpinnerValueChanged(app, event)
        app.CheckEstimatedError(app.Estimated_thicknesses, app.G);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionalityDropDownLabel = uilabel(app.LeftPanel);
            app.FunctionalityDropDownLabel.Text = 'Functionality';
            app.FunctionalityDropDown = uidropdown(app.LeftPanel);
            app.FunctionalityDropDown.Items = {'Simulate Spillage', 'Bayesian Statistics', 'Constellations', 'Experimental data'};
            app.FunctionalityDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionalityDropDownValueChanged, true);
            app.FunctionalityDropDown.Value = 'Simulate Spillage';
            app.InputsPanel = uipanel(app.LeftPanel);
            app.InputsPanel.Title = ' Inputs';
            app.ObservationsMLabel = uilabel(app.InputsPanel);
            app.ObservationsMLabel.Text = 'Observations (M)';
            app.ObservationsMSpinner = uispinner(app.InputsPanel);
            app.ObservationsMSpinner.Limits = [1 Inf];
            app.ObservationsMSpinner.ValueChangedFcn = createCallbackFcn(app, @ObservationsMSpinnerValueChanged, true);
            app.ObservationsMSpinner.Value = 1;
            app.ThicknessstepmmEditFieldLabel = uilabel(app.InputsPanel);
            app.ThicknessstepmmEditFieldLabel.Text = 'Thickness step (mm)';
            app.ThicknessstepmmEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ThicknessstepmmEditField.Limits = [0 Inf];
            app.ThicknessstepmmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessstepmmEditFieldValueChanged, true);
            app.ThicknessstepmmEditField.Value = 1;
            app.IncidentangledegreeEditFieldLabel = uilabel(app.InputsPanel);
            app.IncidentangledegreeEditFieldLabel.Text = 'Incident angle (degree)';
            app.IncidentangledegreeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.IncidentangledegreeEditField.ValueChangedFcn = createCallbackFcn(app, @IncidentangledegreeEditFieldValueChanged, true);
            app.FrequenciesGHzEditFieldLabel = uilabel(app.InputsPanel);
            app.FrequenciesGHzEditFieldLabel.Text = 'Frequencies (GHz)';
            app.FrequenciesGHzEditField = uieditfield(app.InputsPanel, 'text');
            app.FrequenciesGHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequenciesGHzEditFieldValueChanged, true);
            app.FrequenciesGHzEditField.Value = '4,12';
            app.tminEditFieldLabel = uilabel(app.InputsPanel);
            app.tminEditFieldLabel.Text = 'tmin';
            app.tminEditField = uieditfield(app.InputsPanel, 'numeric');
            app.tminEditField.Limits = [0 Inf];
            app.tminEditField.ValueChangedFcn = createCallbackFcn(app, @tminEditFieldValueChanged, true);
            app.tmaxEditFieldLabel = uilabel(app.InputsPanel);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.InputsPanel, 'numeric');
            app.tmaxEditField.Limits = [0 Inf];
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @tmaxEditFieldValueChanged, true);
            app.tmaxEditField.Value = 10;
            app.EnvironmentPropertiesPanel = uipanel(app.LeftPanel);
            app.EnvironmentPropertiesPanel.Title = ' Environment Properties';
            app.RoughnessEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.RoughnessEditFieldLabel.Text = 'Roughness';
            app.RoughnessEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.RoughnessEditField.ValueChangedFcn = createCallbackFcn(app, @RoughnessEditFieldValueChanged, true);
            app.OildielectricEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.OildielectricEditFieldLabel.Text = 'Oil dielectric';
            app.OildielectricEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.OildielectricEditField.ValueChangedFcn = createCallbackFcn(app, @OildielectricEditFieldValueChanged, true);
            app.OildielectricEditField.Value = 3;
            app.AirdielectricEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.AirdielectricEditFieldLabel.Text = 'Air dielectric';
            app.AirdielectricEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.AirdielectricEditField.ValueChangedFcn = createCallbackFcn(app, @AirdielectricEditFieldValueChanged, true);
            app.AirdielectricEditField.Value = 1;
            app.WaterTempertatureCEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterTempertatureCEditFieldLabel.Text = 'Water Tempertature (°C)';
            app.WaterTempertatureCEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterTempertatureCEditField.ValueChangedFcn = createCallbackFcn(app, @WaterTempertatureCEditFieldValueChanged, true);
            app.WaterTempertatureCEditField.Value = 20;
            app.WaterSalinitypptEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterSalinitypptEditFieldLabel.Text = 'Water Salinity (ppt)';
            app.WaterSalinitypptEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterSalinitypptEditField.ValueChangedFcn = createCallbackFcn(app, @WaterSalinitypptEditFieldValueChanged, true);
            app.WaterSalinitypptEditField.Value = 35;
            app.NoisePropertiesPanel = uipanel(app.LeftPanel);
            app.NoisePropertiesPanel.Title = ' Noise Properties';
            app.VarianceEditFieldLabel = uilabel(app.NoisePropertiesPanel);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.NoisePropertiesPanel, 'numeric');
            app.VarianceEditField.ValueChangedFcn = createCallbackFcn(app, @VarianceEditFieldValueChanged, true);
            app.VarianceEditField.Value = 0.02;
            app.SamplesSliderLabel = uilabel(app.NoisePropertiesPanel);
            app.SamplesSliderLabel.Text = 'Samples';
            app.SamplesSlider = uislider(app.NoisePropertiesPanel);
            app.SamplesSlider.Limits = [1 5000];
            app.SamplesSlider.MajorTicks = [1 1000 2000 3000 4000 5000];
            app.SamplesSlider.ValueChangedFcn = createCallbackFcn(app, @SamplesSliderValueChanged, true);
            app.SamplesSlider.Value = 1;
            app.DesiredthicknessEditFieldLabel = uilabel(app.NoisePropertiesPanel);
            app.DesiredthicknessEditFieldLabel.Text = 'Desired thickness';
            app.DesiredthicknessEditField = uieditfield(app.NoisePropertiesPanel, 'numeric');
            app.DesiredthicknessEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredthicknessEditFieldValueChanged, true);
            app.SamplesDisplayEditFieldLabel = uilabel(app.NoisePropertiesPanel);
            app.SamplesDisplayEditFieldLabel.Visible = 'off';
            app.SamplesDisplayEditFieldLabel.Text = 'Samples Display';
            app.SamplesDisplayEditField = uieditfield(app.NoisePropertiesPanel, 'numeric');
            app.SamplesDisplayEditField.Editable = 'off';
            app.SamplesDisplayEditField.Value = 1;
            app.SpillButton = uibutton(app.LeftPanel, 'push');
            app.SpillButton.ButtonPushedFcn = createCallbackFcn(app, @SpillButtonPushed, true);
            app.SpillButton.Text = 'Spill';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AdditionalPlotsPanel = uipanel(app.LeftPanel);
            app.AdditionalPlotsPanel.Title = ' Additional Plots';
            app.OptionsDropDownLabel = uilabel(app.AdditionalPlotsPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.AdditionalPlotsPanel);
            app.OptionsDropDown.Items = {'Probability of detection', 'Thickness Estimation'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Probability of detection';
            app.GenerateforallfrequencytriadsButton = uibutton(app.AdditionalPlotsPanel, 'push');
            app.GenerateforallfrequencytriadsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateforallfrequencytriadsButtonPushed, true);
            app.GenerateforallfrequencytriadsButton.Text = 'Generate for all frequency triads';
            app.GenerateforallfrequencypairsButton = uibutton(app.AdditionalPlotsPanel, 'push');
            app.GenerateforallfrequencypairsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateforallfrequencypairsButtonPushed, true);
            app.GenerateforallfrequencypairsButton.Text = 'Generate for all frequency pairs';
            app.JointpdfdetectionCheckBox = uicheckbox(app.AdditionalPlotsPanel);
            app.JointpdfdetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @JointpdfdetectionCheckBoxValueChanged, true);
            app.JointpdfdetectionCheckBox.Text = 'Joint pdf detection';
            app.JointpdfdetectionCheckBox.Value = true;
            app.DefaultdetectionCheckBox = uicheckbox(app.AdditionalPlotsPanel);
            app.DefaultdetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultdetectionCheckBoxValueChanged, true);
            app.DefaultdetectionCheckBox.Text = 'Default detection';
            app.TestingDetailsPanel = uipanel(app.LeftPanel);
            app.TestingDetailsPanel.Title = 'Testing Details';
            app.ThicknessEditFieldLabel = uilabel(app.TestingDetailsPanel);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.TestingDetailsPanel, 'numeric');
            app.ThicknessEditField.Limits = [3 9];
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.ThicknessEditField.Value = 3;
            app.ChangeDataFolderButton = uibutton(app.TestingDetailsPanel, 'push');
            app.ChangeDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDataFolderButtonPushed, true);
            app.ChangeDataFolderButton.Text = 'Change Data Folder';
            app.DirectoryEditFieldLabel = uilabel(app.TestingDetailsPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.TestingDetailsPanel, 'text');
            app.DirectoryEditField.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.ShowhistogramButton = uibutton(app.RightPanel, 'push');
            app.ShowhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowhistogramButtonPushed, true);
            app.ShowhistogramButton.Text = 'Show histogram';
            app.PlotsDropDownLabel = uilabel(app.RightPanel);
            app.PlotsDropDownLabel.Text = 'Plots';
            app.PlotsDropDown = uidropdown(app.RightPanel);
            app.PlotsDropDown.Items = {'Reflectivity vs. thickness', 'Probability of detection vs. thickness', 'Joint pdf'};
            app.PlotsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotsDropDownValueChanged, true);
            app.PlotsDropDown.Value = 'Reflectivity vs. thickness';
            app.ClearfiguresButton = uibutton(app.RightPanel, 'push');
            app.ClearfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ClearfiguresButtonPushed, true);
            app.ClearfiguresButton.Text = 'Clear figures';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ShowGridLinesCheckBox = uicheckbox(app.RightPanel);
            app.ShowGridLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGridLinesCheckBoxValueChanged, true);
            app.ShowGridLinesCheckBox.Text = 'Show Grid Lines';
            app.ThicknessEstimatedLabel = uilabel(app.RightPanel);
            app.ThicknessEstimatedLabel.Text = 'Thickness Estimated';
            app.ThicknessEstimated = uieditfield(app.RightPanel, 'numeric');
            app.ThicknessEstimated.Editable = 'off';
            app.ProbabilityoferrorEditFieldLabel = uilabel(app.RightPanel);
            app.ProbabilityoferrorEditFieldLabel.Text = 'Probability of error';
            app.ProbabilityoferrorEditField = uieditfield(app.RightPanel, 'numeric');
            app.ProbabilityoferrorEditField.Editable = 'off';
            app.MaximumErrorEditFieldLabel = uilabel(app.RightPanel);
            app.MaximumErrorEditFieldLabel.Text = 'Maximum Error';
            app.MaximumErrorEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaximumErrorEditField.Editable = 'off';
            app.mmSpinnerLabel = uilabel(app.RightPanel);
            app.mmSpinnerLabel.Text = '+/- (mm)';
            app.mmSpinner = uispinner(app.RightPanel);
            app.mmSpinner.Limits = [0 Inf];
            app.mmSpinner.ValueChangedFcn = createCallbackFcn(app, @mmSpinnerValueChanged, true);
            app.Default = uicheckbox(app.RightPanel);
            app.Default.ValueChangedFcn = createCallbackFcn(app, @DefaultValueChanged, true);
            app.Default.Text = 'Default algorithm';
            app.Jointpdf = uicheckbox(app.RightPanel);
            app.Jointpdf.ValueChangedFcn = createCallbackFcn(app, @JointpdfValueChanged, true);
            app.Jointpdf.Text = {'Joint pdf'; ''};
            app.Jointpdf.Value = true;
            app.ExtractFigureButton = uibutton(app.RightPanel, 'push');
            app.ExtractFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFigureButtonPushed, true);
            app.ExtractFigureButton.Text = 'Extract Figure';
            app.ExtractFigureButton_2 = uibutton(app.RightPanel, 'push');
            app.ExtractFigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExtractFigureButton_2Pushed, true);
            app.ExtractFigureButton_2.Text = 'Extract Figure';
            app.ExtractFigureButton_3 = uibutton(app.RightPanel, 'push');
            app.ExtractFigureButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExtractFigureButton_3Pushed, true);
            app.ExtractFigureButton_3.Text = 'Extract Figure';
            app.ProbabilityOfOilDetectionEditFieldLabel = uilabel(app.RightPanel);
            app.ProbabilityOfOilDetectionEditFieldLabel.Text = 'Probability Of Oil Detection';
            app.ProbabilityOfOilDetectionEditField = uieditfield(app.RightPanel, 'numeric');
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'TUKL_LOGO_4C.svg.png';
            app.DetectorErrorPercentageEditFieldLabel = uilabel(app.RightPanel);
            app.DetectorErrorPercentageEditFieldLabel.Text = 'Detector Error Percentage';
            app.DetectorErrorPercentageEditField = uieditfield(app.RightPanel, 'numeric');
            app.EstimatorErrorPercentageEditFieldLabel = uilabel(app.RightPanel);
            app.EstimatorErrorPercentageEditFieldLabel.Text = 'Estimator Error Percentage';
            app.EstimatorErrorPercentageEditField = uieditfield(app.RightPanel, 'numeric');
            app.EstimationerrormarginSpinnerLabel = uilabel(app.RightPanel);
            app.EstimationerrormarginSpinnerLabel.Text = 'Estimation error margin';
            app.EstimationerrormarginSpinner = uispinner(app.RightPanel);
            app.EstimationerrormarginSpinner.ValueChangedFcn = createCallbackFcn(app, @EstimationerrormarginSpinnerValueChanged, true);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OilSpillSimulator

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end