classdef Plots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes3   matlab.ui.control.UIAxes
        UIAxes4   matlab.ui.control.UIAxes
        UIAxes5   matlab.ui.control.UIAxes
        UIAxes6   matlab.ui.control.UIAxes
        UIAxes7   matlab.ui.control.UIAxes
        UIAxes8   matlab.ui.control.UIAxes
        UIAxes9   matlab.ui.control.UIAxes
        UIAxes10  matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callingapp % Description
    end
    
    methods (Access = private)
        
        function Estimate_thickness(app, OilSpillSimulator, data, tmin, tmax, number_of_frequencies)
            app.callingapp = OilSpillSimulator;
            clims = [tmin, tmax];
            
            if number_of_frequencies == 3
                % for frequency triads
                imagesc(app.UIAxes, data(:, :, 1), clims);
                axis(app.UIAxes, 'tight');
                title(app.UIAxes, 'f = [5 12 12] Ghz');
                colormap(app.UIAxes, "jet");
                colorbar(app.UIAxes);
                
                imagesc(app.UIAxes2, data(:, :, 2), clims);
                axis(app.UIAxes2, 'tight');
                title(app.UIAxes2, 'f = [6 12 12] Ghz');
                colormap(app.UIAxes2, "jet");
                colorbar(app.UIAxes2);
                
                imagesc(app.UIAxes3, data(:, :, 3), clims);
                axis(app.UIAxes3, 'tight');
                title(app.UIAxes3, 'f = [9 9 12] Ghz');
                colormap(app.UIAxes3, "jet");
                colorbar(app.UIAxes3);
                
                imagesc(app.UIAxes4, data(:, :, 4), clims);
                axis(app.UIAxes4, 'tight');
                title(app.UIAxes4, 'f = [7 9 12] Ghz');
                colormap(app.UIAxes4, "jet");
                colorbar(app.UIAxes4);
                
                imagesc(app.UIAxes5, data(:, :, 5), clims);
                axis(app.UIAxes5, 'tight');
                title(app.UIAxes5, 'f = [9 12 12] Ghz');
                colormap(app.UIAxes5, "jet");
                colorbar(app.UIAxes5);
                
                imagesc(app.UIAxes6, data(:, :, 6), clims);
                axis(app.UIAxes6, 'tight');
                title(app.UIAxes6, 'f = [8 10 10] Ghz');
                colormap(app.UIAxes6, "jet");
                colorbar(app.UIAxes6);
                
                imagesc(app.UIAxes7, data(:, :, 7), clims);
                axis(app.UIAxes7, 'tight');
                title(app.UIAxes7, 'f = [7 9 9] Ghz');
                colormap(app.UIAxes7, "jet");
                colorbar(app.UIAxes7);
                
                imagesc(app.UIAxes8, data(:, :, 8), clims);
                axis(app.UIAxes8, 'tight');
                title(app.UIAxes8, 'f = [7 8 12] Ghz');
                colormap(app.UIAxes8, "jet");
                colorbar(app.UIAxes8);
                
                imagesc(app.UIAxes9, data(:, :, 9), clims);
                axis(app.UIAxes9, 'tight');
                title(app.UIAxes9, 'f = [4 12 12] Ghz');
                colormap(app.UIAxes9, "jet");
                colorbar(app.UIAxes9);
                
                imagesc(app.UIAxes10, data(:, :, 10), clims);
                axis(app.UIAxes10, 'tight');
                title(app.UIAxes10, 'f = [4 11 12] Ghz');
                colormap(app.UIAxes10, "jet");
                colorbar(app.UIAxes10);
                
            else
                % for frequency pairs
                 imagesc(app.UIAxes, data(:, :, 1), clims);
                axis(app.UIAxes, 'tight');
                title(app.UIAxes, 'f = [6 12] Ghz');
                colormap(app.UIAxes, "jet");
                colorbar(app.UIAxes);
                
                imagesc(app.UIAxes2, data(:, :, 2), clims);
                axis(app.UIAxes2, 'tight');
                title(app.UIAxes2, 'f = [6 12] Ghz');
                colormap(app.UIAxes2, "jet");
                colorbar(app.UIAxes2);
                
                imagesc(app.UIAxes3, data(:, :, 3), clims);
                axis(app.UIAxes3, 'tight');
                title(app.UIAxes3, 'f = [4 12] Ghz');
                colormap(app.UIAxes3, "jet");
                colorbar(app.UIAxes3);
                
                imagesc(app.UIAxes4, data(:, :, 4), clims);
                axis(app.UIAxes4, 'tight');
                title(app.UIAxes4, 'f = [10 11] Ghz');
                colormap(app.UIAxes4, "jet");
                colorbar(app.UIAxes4);
                
                imagesc(app.UIAxes5, data(:, :, 5), clims);
                axis(app.UIAxes5, 'tight');
                title(app.UIAxes5, 'f = [9 9] Ghz');
                colormap(app.UIAxes5, "jet");
                colorbar(app.UIAxes5);
                
                imagesc(app.UIAxes6, data(:, :, 6), clims);
                axis(app.UIAxes6, 'tight');
                title(app.UIAxes6, 'f = [8 12] Ghz');
                colormap(app.UIAxes6, "jet");
                colorbar(app.UIAxes6);
                
                imagesc(app.UIAxes7, data(:, :, 7), clims);
                axis(app.UIAxes7, 'tight');
                title(app.UIAxes7, 'f = [7 10] Ghz');
                colormap(app.UIAxes7, "jet");
                colorbar(app.UIAxes7);
                
                imagesc(app.UIAxes8, data(:, :, 8), clims);
                axis(app.UIAxes8, 'tight');
                title(app.UIAxes8, 'f = [6 8] Ghz');
                colormap(app.UIAxes8, "jet");
                colorbar(app.UIAxes8);
                
                imagesc(app.UIAxes9, data(:, :, 9), clims);
                axis(app.UIAxes9, 'tight');
                title(app.UIAxes9, 'f = [4 8] Ghz');
                colormap(app.UIAxes9, "jet");
                colorbar(app.UIAxes9);
                
                imagesc(app.UIAxes10, data(:, :, 10), clims);
                axis(app.UIAxes10, 'tight');
                title(app.UIAxes10, 'f = [4 12] Ghz');
                colormap(app.UIAxes10, "jet");
                colorbar(app.UIAxes10);
            end
        end
        
        
        function Detect(app, OilSpillSimulator, data, number_of_frequencies)
            app.callingapp = OilSpillSimulator;
            clims = [0, 1];
            cmp = gray;
            cmp = flipud(cmp);
            
            if number_of_frequencies == 3
                % for frequency triads
                imagesc(app.UIAxes, data(:, :, 1), clims);
                axis(app.UIAxes, 'tight');
                title(app.UIAxes, 'f = [5 12 12] Ghz');
                colormap(app.UIAxes, cmp);
                colorbar(app.UIAxes)
                
                imagesc(app.UIAxes2, data(:, :, 2), clims);
                axis(app.UIAxes2, 'tight');
                title(app.UIAxes2, 'f = [6 12 12] Ghz');
                colormap(app.UIAxes2, cmp);
                colorbar(app.UIAxes2);
                
                imagesc(app.UIAxes3, data(:, :, 3), clims);
                axis(app.UIAxes3, 'tight');
                title(app.UIAxes3, 'f = [9 9 12] Ghz');
                colormap(app.UIAxes3, cmp);
                colorbar(app.UIAxes3);
                
                imagesc(app.UIAxes4, data(:, :, 4), clims);
                axis(app.UIAxes4, 'tight');
                title(app.UIAxes4, 'f = [7 9 12] Ghz');
                colormap(app.UIAxes4, cmp);
                colorbar(app.UIAxes4);
                
                imagesc(app.UIAxes5, data(:, :, 5), clims);
                axis(app.UIAxes5, 'tight');
                title(app.UIAxes5, 'f = [9 12 12] Ghz');
                colormap(app.UIAxes5, cmp);
                colorbar(app.UIAxes5);
                
                imagesc(app.UIAxes6, data(:, :, 6), clims);
                axis(app.UIAxes6, 'tight');
                title(app.UIAxes6, 'f = [8 10 10] Ghz');
                colormap(app.UIAxes6, cmp);
                colorbar(app.UIAxes6);
                
                imagesc(app.UIAxes7, data(:, :, 7), clims);
                axis(app.UIAxes7, 'tight');
                title(app.UIAxes7, 'f = [7 9 9] Ghz');
                colormap(app.UIAxes7, cmp);
                colorbar(app.UIAxes7);
                
                imagesc(app.UIAxes8, data(:, :, 8), clims);
                axis(app.UIAxes8, 'tight');
                title(app.UIAxes8, 'f = [7 8 12] Ghz');
                colormap(app.UIAxes8, cmp);
                colorbar(app.UIAxes8);
                
                imagesc(app.UIAxes9, data(:, :, 9), clims);
                axis(app.UIAxes9, 'tight');
                title(app.UIAxes9, 'f = [4 12 12] Ghz');
                colormap(app.UIAxes9, cmp);
                colorbar(app.UIAxes9);
                
                imagesc(app.UIAxes10, data(:, :, 10), clims);
                axis(app.UIAxes10, 'tight');
                title(app.UIAxes10, 'f = [4 11 12] Ghz');
                colormap(app.UIAxes10, cmp);
                colorbar(app.UIAxes10);
                
            else
                % for frequency pairs
                imagesc(app.UIAxes, data(:, :, 1), clims);
                axis(app.UIAxes, 'tight');
                title(app.UIAxes, 'f = [6 12] Ghz');
                colormap(app.UIAxes, cmp);
                colorbar(app.UIAxes);
                
                imagesc(app.UIAxes2, data(:, :, 2), clims);
                axis(app.UIAxes2, 'tight');
                title(app.UIAxes2, 'f = [6 12] Ghz');
                colormap(app.UIAxes2, cmp);
                colorbar(app.UIAxes2);
                
                imagesc(app.UIAxes3, data(:, :, 3), clims);
                axis(app.UIAxes3, 'tight');
                title(app.UIAxes3, 'f = [4 12] Ghz');
                colormap(app.UIAxes3, cmp);
                colorbar(app.UIAxes3);
                
                imagesc(app.UIAxes4, data(:, :, 4), clims);
                axis(app.UIAxes4, 'tight');
                title(app.UIAxes4, 'f = [10 11] Ghz');
                colormap(app.UIAxes4, cmp);
                colorbar(app.UIAxes4);
                
                imagesc(app.UIAxes5, data(:, :, 5), clims);
                axis(app.UIAxes5, 'tight');
                title(app.UIAxes5, 'f = [9 9] Ghz');
                colormap(app.UIAxes5, cmp);
                colorbar(app.UIAxes5);
                
                imagesc(app.UIAxes6, data(:, :, 6), clims);
                axis(app.UIAxes6, 'tight');
                title(app.UIAxes6, 'f = [8 12] Ghz');
                colormap(app.UIAxes6, cmp);
                colorbar(app.UIAxes6);
                
                imagesc(app.UIAxes7, data(:, :, 7), clims);
                axis(app.UIAxes7, 'tight');
                title(app.UIAxes7, 'f = [7 10] Ghz');
                colormap(app.UIAxes7, cmp);
                colorbar(app.UIAxes7);
                
                imagesc(app.UIAxes8, data(:, :, 8), clims);
                axis(app.UIAxes8, 'tight');
                title(app.UIAxes8, 'f = [6 8] Ghz');
                colormap(app.UIAxes8, cmp);
                colorbar(app.UIAxes8);
                
                imagesc(app.UIAxes9, data(:, :, 9), clims);
                axis(app.UIAxes9, 'tight');
                title(app.UIAxes9, 'f = [4 8] Ghz');
                colormap(app.UIAxes9, cmp);
                colorbar(app.UIAxes9);
                
                imagesc(app.UIAxes10, data(:, :, 10), clims);
                axis(app.UIAxes10, 'tight');
                title(app.UIAxes10, 'f = [4 12] Ghz');
                colormap(app.UIAxes10, cmp);
                colorbar(app.UIAxes10);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data, tmin, tmax, number_of_frequencies, options)

            if options == "Probability of detection"
                Detect(app, OilSpillSimulator, data, number_of_frequencies)   
            else
                Estimate_thickness(app, OilSpillSimulator, data, tmin, tmax, number_of_frequencies)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.UIFigure);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plots(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end