classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FunctionalityDropDownLabel      matlab.ui.control.Label
        FunctionalityDropDown           matlab.ui.control.DropDown
        InputsPanel                     matlab.ui.container.Panel
        ObservationsMLabel              matlab.ui.control.Label
        ObservationsMSpinner            matlab.ui.control.Spinner
        ThicknessstepmmEditFieldLabel   matlab.ui.control.Label
        ThicknessstepmmEditField        matlab.ui.control.NumericEditField
        IncidentangledegreeEditFieldLabel  matlab.ui.control.Label
        IncidentangledegreeEditField    matlab.ui.control.NumericEditField
        FrequenciesGHzEditFieldLabel    matlab.ui.control.Label
        FrequenciesGHzEditField         matlab.ui.control.EditField
        tminEditFieldLabel              matlab.ui.control.Label
        tminEditField                   matlab.ui.control.NumericEditField
        tmaxEditFieldLabel              matlab.ui.control.Label
        tmaxEditField                   matlab.ui.control.NumericEditField
        EnvironmentPropertiesPanel      matlab.ui.container.Panel
        RoughnessEditFieldLabel         matlab.ui.control.Label
        RoughnessEditField              matlab.ui.control.NumericEditField
        OildielectricEditFieldLabel     matlab.ui.control.Label
        OildielectricEditField          matlab.ui.control.NumericEditField
        AirdielectricEditFieldLabel     matlab.ui.control.Label
        AirdielectricEditField          matlab.ui.control.NumericEditField
        WaterTempertatureCEditFieldLabel  matlab.ui.control.Label
        WaterTempertatureCEditField     matlab.ui.control.NumericEditField
        WaterSalinitypptEditFieldLabel  matlab.ui.control.Label
        WaterSalinitypptEditField       matlab.ui.control.NumericEditField
        NoisePropertiesPanel            matlab.ui.container.Panel
        VarianceEditFieldLabel          matlab.ui.control.Label
        VarianceEditField               matlab.ui.control.NumericEditField
        SamplesSliderLabel              matlab.ui.control.Label
        SamplesSlider                   matlab.ui.control.Slider
        DesiredthicknessEditFieldLabel  matlab.ui.control.Label
        DesiredthicknessEditField       matlab.ui.control.NumericEditField
        SpillButton                     matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        AdditionalPlotsPanel            matlab.ui.container.Panel
        OptionsDropDownLabel            matlab.ui.control.Label
        OptionsDropDown                 matlab.ui.control.DropDown
        GenerateforallfrequencytriadsButton  matlab.ui.control.Button
        GenerateforallfrequencypairsButton  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        ShowhistogramButton             matlab.ui.control.Button
        PlotsDropDownLabel              matlab.ui.control.Label
        PlotsDropDown                   matlab.ui.control.DropDown
        ClearfigureButton               matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        ShowGridLinesCheckBox           matlab.ui.control.CheckBox
        ThicknessEstimatedLabel         matlab.ui.control.Label
        ThicknessEstimated              matlab.ui.control.NumericEditField
        ProbabilityoferrorEditFieldLabel  matlab.ui.control.Label
        ProbabilityoferrorEditField     matlab.ui.control.NumericEditField
        MaximumErrorEditFieldLabel      matlab.ui.control.Label
        MaximumErrorEditField           matlab.ui.control.NumericEditField
        mmSpinnerLabel                  matlab.ui.control.Label
        mmSpinner                       matlab.ui.control.Spinner
        Default                         matlab.ui.control.CheckBox
        Jointpdf                        matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Plots % Description
        n
        H
        G
        frequency
        ks
        E_oil
        E_air
        temp
        salinity
        theta
        M
        variance
        thickness_step
        tmin
        tmax
        Estimated_thickness
        reflectivities
        samples
        options
        functionality
        desired_thickness
        scattered_values
        Thickness_calculated
        MaximumError
        probability_of_error
        error_range
        Calculate_button_pushed
        ReflectivityLegends
        ProbabilityLegends
    end
    
    
    methods (Access = private)
        
        function R = fractalsurface(app, n,H)
            % inputs:
            % n - number of iterations
            % H - Hurst exponent, fractal dimension D = 3-H
            % outputs:
            % row - row location of surface
            % col - column location of surface
            % R - surface matrix
            % Authors: Hui Yang
            % Affiliations:
            % The Pennsylvania State University
            % 310 Leohard Building, University Park, PA
            % Email: yanghui@gmail.com
            % If you find this toolbox useful, please cite the following papers:
            % [1]	Y. Chen and H. Yang, Numerical simulation and pattern characterization of
            % nonlinear spatiotemporal dynamics on fractal surfaces for the whole-heart modeling
            % applications, European Physical Journal, DOI: 10.1140/epjb/e2016-60960-6
            % [2]	B. Yao, F. Imani, A. Sakpal, E. W. Reutzel, and H. Yang*, �Multifractal
            % analysis of image profiles for the characterization and detection of defects
            % in additive manufacturing,� ASME Journal of Manufacturing Science and Engineering,
            % Vol. 140, No. 3, p031014-13, 2017, DOI: 10.1115/1.4037891
            % [3]	F. Imani, B. Yao, R. Chen, P. Rao, and H. Yang*, �Joint multifractal
            % and lacunarity analysis of image profiles for manufacturing quality control�.
            % ASME Journal Manufacturing Science and Engineering, Vol. 141, No. 4,
            % p 044501-7, 2019. DOI: 10.1115/1.4042579
            N=2^n; % size of surface is (N+1)*(N+1)
            L=zeros(N+1,N+1); % initial of surface
            %% first iteration
            R=randn(3,3);
            Initial = [1,2^(n-1)+1,2^n+1];
            for i=1:3
                for j=1:3
                    L(Initial(i),Initial(j))=R(i,j);
                end
            end
            [row,col]=find(L); % location of first iteration
            r=sqrt(length(row));
            %% generation of the rest iterations
            for l=1:n-1
                for i=1:r-1
                    mid(i)=(row(i)+row(i+1))/2;
                    for j=1:r
                        L(mid(i),row(j))=mean([L(row(i),row(j)),L(row(i+1),row(j))])+randn*2^(-H*(l+1));
                        L(row(j),mid(i))=mean([L(row(j),row(i)),L(row(j),row(i+1))])+randn*2^(-H*(l+1));
                    end
                end
                for i=1:r-1
                    for j=1:r-1
                        L(mid(i),mid(j))=mean([L(row(i),row(j)),L(row(i),row(j+1)),L(row(i+1),row(j)),L(row(i+1),row(j+1))])+randn*2^(-H*(l+1));
                    end
                end
                
                [row,col]=find(L);
                r=sqrt(length(row));
                
            end
            R=zeros(r,r);
            for i=1:r
                for j=1:r
                    R(i,j)=L(row(i),row(j));
                end
            end
        end
        
        function Estimate_for_triads(app)
            %calculate plot values;
            f = [[5 12 12]; [6 12 12]; [9 9 12]; [7 9 12]; [9 12 12]; [8 10 10]; [7 9 9]; [7 8 12]; [4 12 12]; [4 11 12]];
            reflectivity_values = double.empty;
            EstimationValues = double.empty;
            
            H = waitbar(0, 'Starting');
            
            for i = 1:1:size(f, 1)
                
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = app.reflectivity(f(i, j), app.G);
                end
                EstimationValues(:, :, i) = app.Estimate_Thickness(reflectivity_values, f(i, :));
                
                if ~ishghandle(H)
                    return;
                end
                waitbar(i/size(f, 1), H, sprintf('Estimating... %d %%', floor(i/size(f, 1)*100)));
            end
            
            close(H);
            
            app.Plots = Plots(app, EstimationValues, app.tmin, app.tmax, size(f,2), app.options);
        end
        
        function Estimate_for_pairs(app)
            %calculate plot values;
            f = [[6 12]; [6 12]; [4 12]; [10 11]; [9 9]; [8 12]; [7 10]; [6 8]; [4 8]; [4 12]];
            reflectivity_values = double.empty;
            EstimationValues = double.empty;
            
            H = waitbar(0, 'Starting');
            
            for i = 1:1:size(f, 1)
                
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = app.reflectivity(f(i, j), app.G);
                end
                EstimationValues(:, :, i) = app.Estimate_Thickness(reflectivity_values, f(i, :));
                if ~ishghandle(H)
                    return;
                end
                waitbar(i/size(f, 1), H, sprintf('Estimating... %d %%', floor(i/size(f, 1)*100)));
            end
            
            close(H);
            
            
            app.Plots = Plots(app, EstimationValues, app.tmin, app.tmax, size(f,2), app.options);
        end
        
        function Detect_for_triads(app)
            %calculate plot values;
            f = [[5 12 12]; [6 12 12]; [9 9 12]; [7 9 12]; [9 12 12]; [8 10 10]; [7 9 9]; [7 8 12]; [4 12 12]; [4 11 12]];
            reflectivity_values = double.empty;
            DetectionValues = double.empty;
            
            H = waitbar(0, 'Starting');
            
            for i = 1:1:size(f, 1)
                
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = app.reflectivity(f(i, j), app.G);
                end
                
                DetectionValues(:, :, i) = app.Detection_probability_default_algorithm(reflectivity_values, f(i, :));
                if ~ishghandle(H)
                    return;
                end
                waitbar(i/size(f, 1), H, sprintf('Detecting... %d %%', floor(i/size(f, 1)*100)));
            end
            
            close(H);
            
            app.Plots = Plots(app, DetectionValues, app.tmin, app.tmax, size(f,2), app.options);
        end
        
        function Detect_for_pairs(app)
            
            f = [[6 12]; [6 12]; [4 12]; [10 11]; [9 9]; [8 12]; [7 10]; [6 8]; [4 8]; [4 12]];
            reflectivity_values = double.empty;
            DetectionValues = double.empty;
            
            H = waitbar(0, 'Starting');
            
            for i = 1:1:size(f, 1)
                
                for j =1:1:length(f(i, :))
                    reflectivity_values(:, :, j) = app.reflectivity(f(i, j), app.G);
                end
                
                DetectionValues(:, :, i) = app.Detection_probability_default_algorithm(reflectivity_values, f(i, :));
                if ~ishghandle(H)
                    return;
                end
                waitbar(i/size(f, 1), H, sprintf('Detecting... %d %%', floor(i/size(f, 1)*100)));
            end
            
            close(H);
            
            app.Plots = Plots(app, DetectionValues, app.tmin, app.tmax, size(f,2), app.options);
        end


        function R = reflectivity(app, frequency, thickness)
            
            
            %%  Water Dielectric constant and field reflection coefficients
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            E_Water = app.E_water(frequency);
            raw12 = app.field_reflection_coefficient(sqrt(app.E_air), sqrt(app.E_oil));
            raw23  = app.field_reflection_coefficient(sqrt(app.E_oil), sqrt(E_Water));
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%  Phase shift & Reflectivity
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            delta = (2*pi*10^9*sqrt(app.E_oil)*thickness*10^(-3))/(3*10^8);
            delta = transpose(frequency).*delta;
            %     num = (raw12.^2 + transpose(raw23.^2)  + 2*raw12.*transpose(raw23).*cos(2.*delta));
            %     denum = 1 + transpose((raw12.*raw23).^2)+2*raw12.*transpose(raw23).*cos(2.*delta);
            %     R = num./denum;
            
            num = raw12*exp(1i*delta) + transpose(raw23).*exp(-1i*delta);
            denum = exp(1i*delta) + (raw12 .* transpose(raw23) .* exp(-1i*delta));
            R = abs(num./denum).^2;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% Coherent Reflectivity
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            R = coherent_reflectivity(app, R);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
        end
        
        function r = field_reflection_coefficient(app, dielectric_constant1, dielectric_constant2)
            r = (dielectric_constant1 - dielectric_constant2)./(dielectric_constant1 + dielectric_constant2);
            
        end
        
        function E = E_water(app, f)
            %frequency is given in GHz
            t = app.temp;
            S = app.salinity;
            
            %Conductivity
            
            A = [2.903602, 8.607e-2, 4.738817e-4, -2.991e-6, 4.3041e-9];
            sig35 = A(1) + A(2)*t + A(3)*t*t + A(4)*t*t*t + A(5)*t*t*t*t;
            
            A = [37.5109, 5.45216, 0.014409, 1004.75, 182.283];
            P = S * ((A(1) + A(2)*S + A(3)*S*S) / (A(4) + A(5)*S + S*S));
            
            A = [6.9431, 3.2841, -0.099486, 84.85, 69.024];
            alpha0 = (A(1) + A(2)*S + A(3)*S*S) / (A(4) + A(5)*S + S*S);
            
            A = [49.843, -0.2276, 0.00198];
            alpha1 = A(1) + A(2)*S + A(3)*S*S;
            
            Q = 1 + ((alpha0*(t-15))/(t+alpha1));
            
            sigma = sig35*P*Q;
            
            %some used parameters
            a=[0.46606917e-2 -0.26087876e-4 -0.63926782e-5 0.63000075e1 0.26242021e-2 -0.42984155e-2 ...
                0.34414691e-4 0.17667420e-3 -0.20491560e-6 0.58366888e3 0.12634992e3 0.69227972e-4 ...
                0.38957681e-6 0.30742330e3 0.12634992e3 0.37245044e1 0.92609781e-2 -0.26093754e-1];
            
            
            epsS = 87.85306*exp(-0.00456992*t - a(1)*S - a(2)*S*S - a(3)*S*t);
            epsOne = a(4)*exp(-a(5)*t-a(6)*S-a(7)*S*t);
            tau1 = (a(8)+a(9)*S)*exp(a(10)/(t+a(11)));
            tau2 = (a(12)+a(13)*S)*exp(a(14)/(t+a(15)));
            epsInf = a(16) + a(17)*t + a(18)*S;
            
            %Complex Permitivity Calculation
            eps = ((epsS-epsOne)./(1-1i*2*pi.*f.*tau1)) + ((epsOne-epsInf)./(1-1i*2*pi.*f.*tau2)) + (epsInf) + 1i*((17.9751*sigma)./f);
            
            %Seperates real and imaginary components
            epsr = real(eps);
            epsi = imag(eps);
            
            E = epsr - epsi*1i;              % According to Microwave Radar and Radiometric Remote Sensing by David Gardner Long , Fawwaz T. Ulaby p.124
            
        end
        
        function probability_of_detection = probability_of_detection(app)
            
            thickness_range = app.tmin:app.thickness_step:app.tmax;      %thickness over which the reflectivities will be calculated
            
            
            %%  Calculate reflectivities for given frequencies, thicknesses, and surface roughness
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            theoretical_reflectivity = app.reflectivity(app.frequency, thickness_range);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %%  Dielectric constant of water at given frequencies & water reflectivity values at a given surface roughness ks
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            E_water_prob = app.E_water(app.frequency);
            R_water_fprob_planar = ones(size(theoretical_reflectivity)) * ((sqrt(app.E_air) - sqrt(E_water_prob))/(sqrt(app.E_air) + sqrt(E_water_prob)))^2;
            R_water_fprob_planar = app.coherent_reflectivity(R_water_fprob_planar);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %%  Generating noise
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % First dimension  : thickness values
            % Second dimension : Noise values
            % Third dimension  : Number of scans (M)
            % Fourth dimension : Frequencies at which the scans are done
            
            % generates random values based on the given variance
            noise = sqrt(app.variance) * randn(length(theoretical_reflectivity), app.samples, app.M, size(theoretical_reflectivity, 1));
            
            % transpose the theoretical reflectivity to add the reflectivity values to the noise generated (each reflecctivity for its correspondant thickness)
            A = transpose(abs(theoretical_reflectivity));
            
            % reshape the reflectivity values to suit the dimensions of the noise
            % matrix
            A = reshape(A , [length(theoretical_reflectivity), 1, 1, size(noise, 4)]);
            
            % Add the reflectivity values to the noise matrix, thus getting the noisy reflectivity values to be studied
            noisy_reflectivity = noise + A;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %%  Probability of detection calculation
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Calculate the probabilty density functions
            h1 = pdf("Normal", noisy_reflectivity, A, sqrt(app.variance));                                    % Oil
            h2 = pdf("Normal", noisy_reflectivity, abs(R_water_fprob_planar(1,1)), sqrt(app.variance));       % Water
            
            % Multiply the pdf values at all frequencies(f) and scans(M)
            x = prod(h1, 4);
            y = prod(h2, 4);
            
            x = prod(x, 3);
            y = prod(y, 3);
            
            % Check how many oil pdf values are greater than water pdf values
            result = gt(x, y);
            
            % Divide by the total number of trials to find the probability
            probability_of_detection(:, :) = sum(transpose(result(:, :)) == 1)/double(app.samples);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        end
        
        function R_coh = coherent_reflectivity(app, reflectivity)
            x = app.ks*cosd(app.theta);
            R_coh = reflectivity.*exp(-4*(x^2));
            
        end
        
        function Detection = Detection_probability_default_algorithm(app, R_oil, frequency)
            if app.Calculate_button_pushed == 1
                w = waitbar(0, 'Starting');
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            E_water_prob = app.E_water(frequency);
            R_water = ones(size(R_oil)) * ((sqrt(app.E_air) - sqrt(E_water_prob))/(sqrt(app.E_air) + sqrt(E_water_prob)))^2;
            R_water = abs(app.coherent_reflectivity(R_water));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 0.2, w, sprintf('Detecting... %d %%', 20));
            end
            
            %%  Generating noise
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            trials = 1;          % Quantity of noisy reflectivity values needed to be generated
            
            % First & Second dimension  : image resolution
            % Third dimension  : number of frequencies used
            % Fourth dimension : Frequencies at which the scans are done
            
            % generates random values based on the given variance
            noise = sqrt(app.variance) * randn(length(R_oil), size(R_oil, 1), length(frequency), trials, app.M);
            
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 0.4, w, sprintf('Detecting... %d %%', 40));
            end
            
            % Add the reflectivity values to the noise matrix, thus getting the noisy reflectivity values to be studied
            noisy_reflectivity = noise + R_oil;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 0.6, w, sprintf('Detecting... %d %%', 60));
            end
            
            
            %%  Probability of detection calculation
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Calculate the probabilty density functions
            h1 = pdf("Normal", noisy_reflectivity, R_oil, sqrt(app.variance));                                    % Oil
            h2 = pdf("Normal", noisy_reflectivity, R_water, sqrt(app.variance));                                                  % Water
            
            % Multiply the pdf values at all frequencies(f) and scans(M)
            x = prod(h1, 5);
            y = prod(h2, 5);
            
            x = prod(x, 3);
            y = prod(y, 3);
            
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 0.8, w, sprintf('Detecting... %d %%', 80));
            end
            
            % Check how many oil pdf values are greater than water pdf values
            result = gt(x, y);
            
            % Divide by the total number of trials to find the probability
            Detection(:, :) = sum(result, 4)/trials;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if app.Calculate_button_pushed == 1
                if ~ishghandle(w)
                    return;
                end
                waitbar( 1, w, sprintf('Detecting... %d %%', 100));
                
                close(w);
            end
        end


        function Estimated_thicknesses = Estimate_Thickness(app, measured_reflectivity, frequency)
            
            if app.Calculate_button_pushed == 1
                w = waitbar(0, 'Starting');
            end
            %% Simulate the M observations
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % generate noise M times to simulate the collection of different observations
            noise = sqrt(app.variance) * randn(size(measured_reflectivity, 2), size(measured_reflectivity, 1), length(frequency), app.M);
            measured_reflectivity = abs(measured_reflectivity + noise);
            measured_reflectivity = sum(measured_reflectivity, 4)/app.M;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %% Estimation & Histogram
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            Estimated_thicknesses = double.empty;
            
            %   use minimum euclidean distance to find estimate the thickness on
            %   each reflectvity value collected
            for j = 1:1:size(measured_reflectivity,2)
                for i = 1:1:size(measured_reflectivity, 1)
                    Estimated_thicknesses(i, j) = app.minimum_euclidean_distance( 10*log10(abs(measured_reflectivity(i, j))), frequency);
                end
                if app.Calculate_button_pushed == 1
                    if ~ishghandle(w)
                        return;
                    end
                    waitbar(i/(size(measured_reflectivity, 1)), w, sprintf('Estimating... %d %%', floor(i/(size(measured_reflectivity, 1))*100)));
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.Calculate_button_pushed == 1
                close(w);
            end
        end
        
        function t = minimum_euclidean_distance(app, measured_reflectivity, frequency)
            
            thickness = app.tmin:app.thickness_step:app.tmax;    %thickness over which the reflectivities will be calculated
            
            %% Creating the theoretical curve by which the measured reflectivities are compared
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            theoretical_reflectivity = 10*log10(abs(app.reflectivity(frequency, thickness)));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% Finding the point closest to the measured reflectivity
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %added absolute value
            distance = sqrt(sum((theoretical_reflectivity-transpose(measured_reflectivity)).^2, 1));
            [~ , indexOfThickness] = min(distance);
            t = thickness(indexOfThickness);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
        end
        
        function scatter_points(app)
            cla(app.UIAxes, 'reset');
            
            thickness = app.tmin:app.thickness_step:app.tmax;        % thickness over which the reflectivities will be calculated
            
            
            %% Creating the theoretical curve by which the measured reflectivities are compared
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            theoretical_reflectivity = abs(app.reflectivity(app.frequency, thickness));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %% Generating the random reflectivities with given frequencies and at a given thickness to test the frequencies
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%s%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            noise = sqrt(app.variance)*randn(length(app.frequency), app.samples, app.M);
            [~, indexOfThickness] = min(abs(thickness-app.desired_thickness));
            noisy_reflectivities = 10*log10(abs(theoretical_reflectivity(:, indexOfThickness) + noise));
            app.scattered_values = sum(noisy_reflectivities, 3)/app.M;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %% Plotting
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if length(app.frequency) == 2
                
                
                % 2D Scatter plots
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                scatter(app.UIAxes, app.scattered_values(1, :), app.scattered_values(2, :), 'x');
                hold(app.UIAxes, 'on');
                scatter(app.UIAxes, 10*log10(abs(theoretical_reflectivity(1, :))), 10*log10(abs(theoretical_reflectivity(2, :))), '.');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                app.UIAxes.XLim = [-8 0];
                app.UIAxes.XLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(1)), "GHz");
                app.UIAxes.YLim = [-8 0];
                app.UIAxes.YLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(2)), "GHz");
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            elseif length(app.frequency) == 3
                
                
                % 3D Scatter plots
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                scatter3(app.UIAxes, app.scattered_values(1, :), app.scattered_values(2, :), app.scattered_values(3, :), 'x');
                hold(app.UIAxes, 'on');
                scatter3(app.UIAxes, 10*log10(abs(theoretical_reflectivity(1, :))), 10*log10(abs(theoretical_reflectivity(2, :))), 10*log10(abs(theoretical_reflectivity(3, :))), '.');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                app.UIAxes.XLim = [-8 0];
                app.UIAxes.XLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(1)), "GHz");
                app.UIAxes.YLim = [-8 0];
                app.UIAxes.YLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(2)), "GHz");
                app.UIAxes.ZLim = [-8 0];
                app.UIAxes.ZLabel.String = strcat("Reflectivity(dB)@", num2str(app.frequency(3)), "GHz");
                
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            else
                app.UIAxes.Visible = "off";
                app.UIAxes2.Position = [69,226,560,456];
            end
        end
        
        
        function analyze_constellation_results(app)
            
            %% Histograms
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            h = double.empty;
            for i = 1:1:size(app.scattered_values, 2)
                % causing error
                h(i) = app.minimum_euclidean_distance(transpose(app.scattered_values(:, i)), app.frequency);
            end
            s = histogram(app.UIAxes2, h);
            grid(app.UIAxes2, 'on');
            app.UIAxes2.XLim = [app.tmin , app.tmax+1];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %% Maximum Error & Probability of error caclulations
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            % Error Calculation
            BinCenters(1) = s.BinLimits(1) + s.BinWidth/2;          % Find the thickness at the which the farthest bin to the left from the actual thickness is plotted
            BinCenters(2) = s.BinLimits(2) - s.BinWidth/2;          % Find the thickness at the which the farthest bin to the right from the actual thickness is plotted
            distance = abs(app.desired_thickness - BinCenters);                         % Find the deveation from the actual value
            [app.MaximumError, ~] = max(distance);                      % The error represents the value with maximum deveation from the actual value
            app.MaximumErrorEditField.Value = app.MaximumError;
            
            % Probability of error
            hist_thickness = s.BinEdges - s.BinWidth/2;                 % Find the centers of the bins created which represent each thickness
            hist_thickness(1) = [];                                     % Delete the first element since it represents nothing ( first binEdge-binWidth/2)
            %[~, indexOfActualThickness] = min(abs(app.desired_thickness- hist_thickness));        % Find the index of the actual thickness
            Number_of_correctly_estimated_thickness = 0;                                                                  % find the number of correctly estimated thicknesses
            
            for i = app.desired_thickness - app.error_range : app.thickness_step : app.desired_thickness + app.error_range
                Number_of_correctly_estimated_thickness = Number_of_correctly_estimated_thickness + sum(h(:) == i);
            end
            
            app.probability_of_error = (length(app.scattered_values) - Number_of_correctly_estimated_thickness)/length(app.scattered_values);           %divide wrong estimations by total estimations
            app.ProbabilityoferrorEditField.Value = app.probability_of_error;
            
            % Estimated thickness
            [~, indexOfEstimatedThickness] = max(s.Values);
            app.Thickness_calculated = hist_thickness(indexOfEstimatedThickness);
            app.ThicknessEstimated.Value = app.Thickness_calculated;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
        
        function UpdateConstellationsPage(app)
            
            if length(app.frequency) == 2 || length(app.frequency) == 3
                app.UIAxes.Position = [114,421,418,292];
                app.UIAxes2.Position = [29,119,316,258];
                app.UIAxes2.Visible = 'on';
                app.ThicknessEstimated.Position = [530,291,100,22];
                app.ThicknessEstimatedLabel.Position =[398,291,117,22];
                app.ProbabilityoferrorEditField.Position = [530,244,100,22];
                app.ProbabilityoferrorEditFieldLabel.Position = [412,244,103,22];
                app.mmSpinner.Position = [530,205,50,22];
                app.mmSpinnerLabel.Position = [464,205,51,22];
                app.MaximumErrorEditField.Position = [530,162,100,22];
                app.MaximumErrorEditFieldLabel.Position = [429,161,87,22];
            else
                app.UIAxes.Position = [69,226,560,456];
                app.UIAxes2.Visible = 'off';
                app.ThicknessEstimated.Position = [529,175,100,22];
                app.ThicknessEstimatedLabel.Position =[397,175,117,22];
                app.ProbabilityoferrorEditField.Position = [529,128,100,22];
                app.ProbabilityoferrorEditFieldLabel.Position = [411,128,103,22];
                app.mmSpinner.Position = [529, 81, 50, 22];
                app.mmSpinnerLabel.Position = [464, 81, 51, 22];
                app.mmSpinner.Step = app.thickness_step;
                app.MaximumErrorEditField.Position = [529, 34, 100, 22];
                app.MaximumErrorEditFieldLabel.Position = [429, 34, 87, 22];
            end
            
        end
        
        function oil_found = Detect_with_joint_pdf_single_point(app, measured_reflectivity, frequency)
            
            thickness = app.tmin:app.thickness_step:app.tmax;
            
            R_oil = app.reflectivity(frequency, thickness);
            
            %%  Dielectric constant of water at given frequencies & water reflectivity values at a given surface roughness ks
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            E_water_prob = app.E_water(frequency);
            R_water = ((sqrt(app.E_air) - sqrt(E_water_prob))/(sqrt(app.E_air) + sqrt(E_water_prob)))^2;
            R_water = abs(app.coherent_reflectivity(R_water));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %%  Generating noise
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % First & Second dimension  : image resolution
            % Third dimension  : number of frequencies used
            % Fourth dimension : Frequencies at which the scans are done


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %%  Probability of detection calculation
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            % generates random values based on the given variance
            noise = sqrt(app.variance) * randn(size(measured_reflectivity, 1), size(measured_reflectivity, 2), app.M);
            
            % Add the reflectivity values to the noise matrix, thus getting the noisy reflectivity values to be studied
            noisy_reflectivity = noise + measured_reflectivity;
            
            % Calculate the probabilty density functions
            h1 = pdf("Normal", noisy_reflectivity, R_oil, sqrt(app.variance));                                    % Oil
            h2 = pdf("Normal", noisy_reflectivity, R_water, sqrt(app.variance));                                                  % Water
            
            x = sum(h1, 2)/length(h1);
            
            % Multiply the pdf values at all frequencies(f) and scans(M)
            x = prod(x, 3);
            y = prod(h2, 3);
            
            x = prod(x, 1);
            y = prod(y, 1);
            
            if x/y >= 1
                oil_found = 1;
            else
                oil_found = 0;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
        end
        
        function oil_found = Detect_with_joint_pdf_multiple_points(app, measured_reflectivity, frequency)
            
            oil_found = double.empty;
            if app.Calculate_button_pushed == 1
                w = waitbar(0, 'Starting');
            end
            for i = 1:1:size(measured_reflectivity, 1)
                for j = 1:1:size(measured_reflectivity, 2)
                    oil_found(i, j) = app.Detect_with_joint_pdf_single_point(measured_reflectivity(i, j), frequency);
                    
                end
                if app.Calculate_button_pushed == 1
                    if ~ishghandle(w)
                        return;
                    end
                    waitbar(i/(size(measured_reflectivity, 1)), w, sprintf('Detecting... %d %%', floor(i/(size(measured_reflectivity, 1))*100)));
                end
            end
            
            if app.Calculate_button_pushed == 1
                close(w);
            end
        end


        function detection = Detect(app, measured_reflectivity, frequency)
            
            if app.Jointpdf.Value == 1
                detection = app.Detect_with_joint_pdf_multiple_points(measured_reflectivity, frequency);
            else
                detection = app.Detection_probability_default_algorithm(measured_reflectivity, frequency);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.frequency= str2double(strsplit(app.FrequenciesGHzEditField.Value,','));
            app.M = app.ObservationsMSpinner.Value;
            app.variance = app.VarianceEditField.Value;
            app.samples = app.SamplesSlider.Value;
            app.ks = app.RoughnessEditField.Value;
            app.E_air = app.AirdielectricEditField.Value;
            app.E_oil = app.OildielectricEditField.Value;
            app.temp = app.WaterTempertatureCEditField.Value;
            app.salinity = app.WaterSalinitypptEditField.Value;
            app.theta = app.IncidentangledegreeEditField.Value;
            app.thickness_step = app.ThicknessstepmmEditField.Value;
            app.tmin = app.tminEditField.Value;
            app.tmax = app.tmaxEditField.Value;
            app.options = app.OptionsDropDown.Value;
            app.functionality = app.FunctionalityDropDown.Value;
            app.desired_thickness = app.DesiredthicknessEditField.Value;
            app.error_range = app.mmSpinner.Value;
            app.DesiredthicknessEditField.Visible = "off";
            app.DesiredthicknessEditFieldLabel.Visible = "off";
            title(app.UIAxes, "Fractal Surface");
            title(app.UIAxes2, "Detection");
            title(app.UIAxes3, "Estimation");
            app.ClearfigureButton.Visible = "on";
            app.PlotsDropDown.Visible = "off";
            app.PlotsDropDownLabel.Visible = "off";
            app.PlotButton.Visible = "off";
            app.ShowGridLinesCheckBox.Visible= "off";
            app.ProbabilityoferrorEditField.Visible = 'off';
            app.ProbabilityoferrorEditFieldLabel.Visible = 'off';
            app.MaximumErrorEditField.Visible = 'off';
            app.MaximumErrorEditFieldLabel.Visible = 'off';
            app.ThicknessEstimated.Visible = 'off';
            app.ThicknessEstimatedLabel.Visible = 'off';
            app.mmSpinner.Visible = "off";
            app.mmSpinnerLabel.Visible = 'off';
            
        end

        % Button pushed function: 
        % GenerateforallfrequencytriadsButton
        function GenerateforallfrequencytriadsButtonPushed(app, event)
            if app.options == "Probability of detection"
                app.Detect_for_triads;
            else
                app.Estimate_for_triads;
            end
        end

        % Button pushed function: GenerateforallfrequencypairsButton
        function GenerateforallfrequencypairsButtonPushed(app, event)
            if app.options == "Probability of detection"
                app.Detect_for_pairs;
            elseif app.options == "Thickness Estimation"
                app.Estimate_for_pairs;
            else
                app.Detect_for_pairs;
                app.Estimate_for_pairs;
                
            end
        end

        % Button pushed function: SpillButton
        function SpillButtonPushed(app, event)
            app.n = 8;        % image resolution will be (2^n + 1)
            app.H = 0.7;
            app.G = fractalsurface(app, app.n, app.H);
            app.G = normalize(app.G, 'range', [app.tmin app.tmax]);
            spill_clims = [app.tmin app.tmax];
            imagesc(app.UIAxes, app.G, spill_clims);
            axis(app.UIAxes, 'tight');
            colormap(app.UIAxes, 'jet');
            colorbar(app.UIAxes);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            app.Calculate_button_pushed = 1;
            measured_reflectivities = double.empty;
            
            %generate reflectivity values
            for i =1:1:length(app.frequency)
                measured_reflectivities(:, :, i) = app.reflectivity(app.frequency(i), app.G);
            end
            
            % calculate the probability of detection
            y = app.Detect(measured_reflectivities, app.frequency);
            clims = [0, 1];
            imagesc(app.UIAxes2, y, clims);
            axis(app.UIAxes2, 'tight');
            title(app.UIAxes2, 'Detection');
            cmp = gray;
            cmp = flipud(cmp);
            colormap(app.UIAxes2, cmp);
            colorbar(app.UIAxes2)
            
            % estimate thicknesses
            app.Estimated_thickness = app.Estimate_Thickness(measured_reflectivities, app.frequency);
            clims = [app.tmin, app.tmax];
            imagesc(app.UIAxes3, app.Estimated_thickness, clims);
            axis(app.UIAxes3, 'tight');
            title(app.UIAxes3, 'Estimation');
            colormap(app.UIAxes3, 'jet');
            colorbar(app.UIAxes3);
            
            app.Calculate_button_pushed = 0;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {754, 754};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {609, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ShowhistogramButton
        function ShowhistogramButtonPushed(app, event)
            clf;
            histogram(app.Estimated_thickness);
        end

        % Value changed function: FrequenciesGHzEditField
        function FrequenciesGHzEditFieldValueChanged(app, event)
            app.frequency= str2double(strsplit(app.FrequenciesGHzEditField.Value,','));
            
            if app.functionality == "Constellations"
                app.UpdateConstellationsPage();
                app.scatter_points();
                app.analyze_constellation_results();
                
            end
            
        end

        % Value changed function: ObservationsMSpinner
        function ObservationsMSpinnerValueChanged(app, event)
            app.M = app.ObservationsMSpinner.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: VarianceEditField
        function VarianceEditFieldValueChanged(app, event)
            app.variance = app.VarianceEditField.Value;
            
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
            
        end

        % Value changed function: SamplesSlider
        function SamplesSliderValueChanged(app, event)
            app.samples = int64(app.SamplesSlider.Value);
            
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
            
        end

        % Value changed function: RoughnessEditField
        function RoughnessEditFieldValueChanged(app, event)
            app.ks = app.RoughnessEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: AirdielectricEditField
        function AirdielectricEditFieldValueChanged(app, event)
            app.E_air = app.AirdielectricEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: OildielectricEditField
        function OildielectricEditFieldValueChanged(app, event)
            app.E_oil = app.OildielectricEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: WaterTempertatureCEditField
        function WaterTempertatureCEditFieldValueChanged(app, event)
            app.temp = app.WaterTempertatureCEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: WaterSalinitypptEditField
        function WaterSalinitypptEditFieldValueChanged(app, event)
            app.salinity = app.WaterSalinitypptEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: IncidentangledegreeEditField
        function IncidentangledegreeEditFieldValueChanged(app, event)
            app.theta = app.IncidentangledegreeEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: ThicknessstepmmEditField
        function ThicknessstepmmEditFieldValueChanged(app, event)
            app.thickness_step = app.ThicknessstepmmEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: tminEditField
        function tminEditFieldValueChanged(app, event)
            app.tmin = app.tminEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: tmaxEditField
        function tmaxEditFieldValueChanged(app, event)
            app.tmax = app.tmaxEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            app.options = app.OptionsDropDown.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
                
            end
        end

        % Value changed function: FunctionalityDropDown
        function FunctionalityDropDownValueChanged(app, event)
            app.functionality = app.FunctionalityDropDown.Value;
            
            if app.functionality == "Simulate Spillage"
                
                cla(app.UIAxes, 'reset');
                cla(app.UIAxes2, 'reset');
                cla(app.UIAxes3, 'reset');
                title(app.UIAxes, "Spillage");
                title(app.UIAxes2, "Detection");
                title(app.UIAxes3, "Estimation");
                app.UIAxes.Position = [55,406,391,284];
                app.UIAxes2.Position = [29,119,316,258];
                app.UIAxes3.Position = [368,116,318,261];
                app.UIAxes.Visible = 'on';
                app.UIAxes2.Visible = "on";
                app.UIAxes3.Visible = "on";
                
                app.SpillButton.Visible = "on";
                app.AdditionalPlotsPanel.Visible = "on";
                app.DesiredthicknessEditField.Visible = "off";
                app.DesiredthicknessEditFieldLabel.Visible = "off";
                app.ShowhistogramButton.Visible = "on";
                app.ObservationsMSpinner.Visible = "on";
                app.PlotsDropDown.Visible = "off";
                app.PlotsDropDownLabel.Visible = "off";
                app.PlotButton.Visible = "off";
                app.ClearfigureButton.Visible = "on";
                app.CalculateButton.Visible = "on";
                app.NoisePropertiesPanel.Visible = "on";
                app.ShowGridLinesCheckBox.Visible= "off";
                app.ProbabilityoferrorEditField.Visible = 'off';
                app.ProbabilityoferrorEditFieldLabel.Visible = 'off';
                app.MaximumErrorEditField.Visible = 'off';
                app.MaximumErrorEditFieldLabel.Visible = 'off';
                app.ThicknessEstimated.Visible = 'off';
                app.ThicknessEstimatedLabel.Visible = 'off';
                app.mmSpinner.Visible = "off";
                app.mmSpinnerLabel.Visible = 'off';
                app.ObservationsMLabel.Visible = 'on';
                app.ObservationsMSpinner.Visible = 'on';
                app.Jointpdf.Visible = 'on';
                app.Default.Visible = 'on';
                
            elseif app.functionality == "Bayesian Statistics"
                
                cla(app.UIAxes, 'reset');
                cla(app.UIAxes2, 'reset');
                cla(app.UIAxes3, 'reset');
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                app.UIAxes2.Visible = "off";
                app.UIAxes3.Visible = "off";
                title(app.UIAxes, "Title");
                app.UIAxes.Position = [62,142,581,468];
                app.UIAxes.XLim = [app.tmin, app.tmax];
                app.SpillButton.Visible = "off";
                app.AdditionalPlotsPanel.Visible = "off";
                app.ShowhistogramButton.Visible = "off";
                app.ObservationsMSpinner.Visible = "on";
                app.PlotsDropDown.Visible = "on";
                app.PlotsDropDownLabel.Visible = "on";
                app.PlotButton.Visible = "on";
                app.ClearfigureButton.Visible = "on";
                app.CalculateButton.Visible = "off";
                app.NoisePropertiesPanel.Visible = "off";
                app.ShowGridLinesCheckBox.Visible = "on";
                title(app.UIAxes, "Reflectivity vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Coherent Reflectivity (dB)";
                app.ProbabilityoferrorEditField.Visible = 'off';
                app.ProbabilityoferrorEditFieldLabel.Visible = 'off';
                app.MaximumErrorEditField.Visible = 'off';
                app.MaximumErrorEditFieldLabel.Visible = 'off';
                app.ThicknessEstimated.Visible = 'off';
                app.ThicknessEstimatedLabel.Visible = 'off';
                app.mmSpinner.Visible = "off";
                app.mmSpinnerLabel.Visible = 'off';
                app.Jointpdf.Visible = 'off';
                app.Default.Visible = 'off';
                
                if app.PlotsDropDown.Value == "Reflectivity vs. thickness"
                    app.ObservationsMLabel.Visible = 'off';
                    app.ObservationsMSpinner.Visible = "off";
                else
                    app.ObservationsMLabel.Visible = 'on';
                    app.ObservationsMSpinner.Visible = "on";
                end
                
                app.ReflectivityLegends = string.empty;
                
            else
                app.SpillButton.Visible = "off";
                app.AdditionalPlotsPanel.Visible = "off";
                app.DesiredthicknessEditField.Visible = "on";
                app.DesiredthicknessEditFieldLabel.Visible = "on";
                app.CalculateButton.Visible = 'off';
                app.PlotsDropDown.Visible = 'off';
                app.PlotsDropDownLabel.Visible = 'off';
                app.PlotButton.Visible = 'off';
                app.ClearfigureButton.Visible = 'off';
                app.NoisePropertiesPanel.Visible = 'on';
                app.ShowGridLinesCheckBox.Visible = 'off';
                app.ShowhistogramButton.Visible = 'off';
                
                app.ProbabilityoferrorEditField.Visible = 'on';
                app.ProbabilityoferrorEditFieldLabel.Visible = 'on';
                app.MaximumErrorEditField.Visible = 'on';
                app.MaximumErrorEditFieldLabel.Visible = 'on';
                app.ThicknessEstimated.Visible = 'on';
                app.ThicknessEstimatedLabel.Visible = 'on';
                app.mmSpinner.Visible = "on";
                app.mmSpinnerLabel.Visible = 'on';
                app.ObservationsMLabel.Visible = 'on';
                app.ObservationsMSpinner.Visible = 'on';
                app.Jointpdf.Visible = 'off';
                app.Default.Visible = 'off';
                
                cla(app.UIAxes, 'reset');
                cla(app.UIAxes2, 'reset');
                cla(app.UIAxes3, 'reset');
                
                app.UIAxes2.Visible = "off";
                app.UIAxes3.Visible = "off";
                title(app.UIAxes, "Title");
                
                app.UpdateConstellationsPage();
                app.scatter_points();
                app.analyze_constellation_results();


            end
        end

        % Value changed function: DesiredthicknessEditField
        function DesiredthicknessEditFieldValueChanged(app, event)
            app.desired_thickness = app.DesiredthicknessEditField.Value;
            if app.functionality == "Constellations"
                app.scatter_points();
                app.analyze_constellation_results();
            end
        end

        % Value changed function: PlotsDropDown
        function PlotsDropDownValueChanged(app, event)
            if app.PlotsDropDown.Value == "Reflectivity vs. thickness"
                app.NoisePropertiesPanel.Visible = "off";
                title(app.UIAxes, "Reflectivity vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Coherent Reflectivity (dB)";
                app.ObservationsMLabel.Visible = 'off';
                app.ObservationsMSpinner.Visible = 'off';
            else
                app.NoisePropertiesPanel.Visible = "on";
                title(app.UIAxes, "Probability of detection vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Probability of detection";
                app.ObservationsMLabel.Visible = 'on';
                app.ObservationsMSpinner.Visible = 'on';
            end
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            thickness_range = app.tmin:app.thickness_step:app.tmax;
            
            if app.PlotsDropDown.Value == "Reflectivity vs. thickness"
                
                R = 10*log10(app.reflectivity(app.frequency, thickness_range));
                plot(app.UIAxes, thickness_range, R);
                hold(app.UIAxes, "on");
                title(app.UIAxes, "Reflectivity vs. Thickness");
                app.UIAxes.XLabel.String = "Oil Thickness (mm)";
                app.UIAxes.YLabel.String = "Coherent Reflectivity (dB)";
                
                reflectivity_legend_strings = string(app.ReflectivityLegends);
                
                for i = 1:1:length(app.frequency)
                    x = string(strcat("f = ", num2str(app.frequency(i)), ", ks = ", num2str(app.ks), ", E_oil = ", num2str(app.E_oil)));
                    reflectivity_legend_strings(length(reflectivity_legend_strings) + 1, 1) = x ;
                end
                
                legend(app.UIAxes, reflectivity_legend_strings);
                app.ReflectivityLegends = reflectivity_legend_strings;
                
            else
                probability = app.probability_of_detection();
                plot(app.UIAxes, thickness_range, probability);
                
                probability_legend_strings = app.ProbabilityLegends;
                
                frequency_string = sprintf('%.0f,' , app.frequency);
                frequency_string = frequency_string(1:end-1);
        
                x = string(strcat("f = ", frequency_string, " GHz", ", ks = ", num2str(app.ks), ", E_oil = ", num2str(app.E_oil)));
                probability_legend_strings(length(probability_legend_strings) + 1, 1) = x ;
                
                app.ProbabilityLegends = probability_legend_strings;
                legend(app.UIAxes, probability_legend_strings);
                
                hold(app.UIAxes, "on");
            end
        end

        % Button pushed function: ClearfigureButton
        function ClearfigureButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.ReflectivityLegends = string.empty;
            app.ProbabilityLegends = string.empty;
        end

        % Value changed function: ShowGridLinesCheckBox
        function ShowGridLinesCheckBoxValueChanged(app, event)
            if app.ShowGridLinesCheckBox.Value == 1
                
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                app.UIAxes.ZGrid = 'on';
                
                app.UIAxes2.XGrid = 'on';
                app.UIAxes2.YGrid = 'on';
                app.UIAxes2.ZGrid = 'on';
                
                app.UIAxes3.XGrid = 'on';
                app.UIAxes3.YGrid = 'on';
                app.UIAxes3.ZGrid = 'on';
                
            else
                
                app.UIAxes.XGrid = 'off';
                app.UIAxes.YGrid = 'off';
                app.UIAxes.ZGrid = 'off';
                
                app.UIAxes2.XGrid = 'off';
                app.UIAxes2.YGrid = 'off';
                app.UIAxes2.ZGrid = 'off';
                
                app.UIAxes3.XGrid = 'off';
                app.UIAxes3.YGrid = 'off';
                app.UIAxes3.ZGrid = 'off';
            end
            
        end

        % Value changed function: mmSpinner
        function mmSpinnerValueChanged(app, event)
            app.mmSpinner.Step = app.thickness_step;
            app.error_range = app.mmSpinner.Value;
            app.analyze_constellation_results();
        end

        % Value changed function: Jointpdf
        function JointpdfValueChanged(app, event)
            app.Default.Value = 1-app.Jointpdf.Value;
            
            if app.FunctionalityDropDown == "Simulate Spillage"
                y = app.Detect(app.reflectivities, app.frequency);
                clims = [0, 1];
                imagesc(app.UIAxes2, y, clims);
                axis(app.UIAxes2, 'tight');
                title(app.UIAxes2, 'Detection');
                cmp = gray;
                cmp = flipud(cmp);
                colormap(app.UIAxes2, cmp);
                colorbar(app.UIAxes2)
            end
            
        end

        % Value changed function: Default
        function DefaultValueChanged(app, event)
            app.Jointpdf.Value = 1 - app.Default.Value;
            
            if app.FunctionalityDropDown == "Simulate Spillage"
                y = app.Detect(app.reflectivities, app.frequency);
                clims = [0, 1];
                imagesc(app.UIAxes2, y, clims);
                axis(app.UIAxes2, 'tight');
                title(app.UIAxes2, 'Detection');
                cmp = gray;
                cmp = flipud(cmp);
                colormap(app.UIAxes2, cmp);
                colorbar(app.UIAxes2)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionalityDropDownLabel = uilabel(app.LeftPanel);
            app.FunctionalityDropDownLabel.Text = 'Functionality';
            app.FunctionalityDropDown = uidropdown(app.LeftPanel);
            app.FunctionalityDropDown.Items = {'Simulate Spillage', 'Bayesian Statistics', 'Constellations'};
            app.FunctionalityDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionalityDropDownValueChanged, true);
            app.FunctionalityDropDown.Value = 'Simulate Spillage';
            app.InputsPanel = uipanel(app.LeftPanel);
            app.InputsPanel.Title = ' Inputs';
            app.ObservationsMLabel = uilabel(app.InputsPanel);
            app.ObservationsMLabel.Text = 'Observations (M)';
            app.ObservationsMSpinner = uispinner(app.InputsPanel);
            app.ObservationsMSpinner.Limits = [1 Inf];
            app.ObservationsMSpinner.ValueChangedFcn = createCallbackFcn(app, @ObservationsMSpinnerValueChanged, true);
            app.ObservationsMSpinner.Value = 1;
            app.ThicknessstepmmEditFieldLabel = uilabel(app.InputsPanel);
            app.ThicknessstepmmEditFieldLabel.Text = 'Thickness step (mm)';
            app.ThicknessstepmmEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ThicknessstepmmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessstepmmEditFieldValueChanged, true);
            app.ThicknessstepmmEditField.Value = 1;
            app.IncidentangledegreeEditFieldLabel = uilabel(app.InputsPanel);
            app.IncidentangledegreeEditFieldLabel.Text = 'Incident angle (degree)';
            app.IncidentangledegreeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.IncidentangledegreeEditField.ValueChangedFcn = createCallbackFcn(app, @IncidentangledegreeEditFieldValueChanged, true);
            app.FrequenciesGHzEditFieldLabel = uilabel(app.InputsPanel);
            app.FrequenciesGHzEditFieldLabel.Text = 'Frequencies (GHz)';
            app.FrequenciesGHzEditField = uieditfield(app.InputsPanel, 'text');
            app.FrequenciesGHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequenciesGHzEditFieldValueChanged, true);
            app.FrequenciesGHzEditField.Value = '4,12';
            app.tminEditFieldLabel = uilabel(app.InputsPanel);
            app.tminEditFieldLabel.Text = 'tmin';
            app.tminEditField = uieditfield(app.InputsPanel, 'numeric');
            app.tminEditField.ValueChangedFcn = createCallbackFcn(app, @tminEditFieldValueChanged, true);
            app.tmaxEditFieldLabel = uilabel(app.InputsPanel);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.InputsPanel, 'numeric');
            app.tmaxEditField.Limits = [0 100];
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @tmaxEditFieldValueChanged, true);
            app.tmaxEditField.Value = 10;
            app.EnvironmentPropertiesPanel = uipanel(app.LeftPanel);
            app.EnvironmentPropertiesPanel.Title = ' Environment Properties';
            app.RoughnessEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.RoughnessEditFieldLabel.Text = 'Roughness';
            app.RoughnessEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.RoughnessEditField.ValueChangedFcn = createCallbackFcn(app, @RoughnessEditFieldValueChanged, true);
            app.OildielectricEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.OildielectricEditFieldLabel.Text = 'Oil dielectric';
            app.OildielectricEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.OildielectricEditField.ValueChangedFcn = createCallbackFcn(app, @OildielectricEditFieldValueChanged, true);
            app.OildielectricEditField.Value = 3;
            app.AirdielectricEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.AirdielectricEditFieldLabel.Text = 'Air dielectric';
            app.AirdielectricEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.AirdielectricEditField.ValueChangedFcn = createCallbackFcn(app, @AirdielectricEditFieldValueChanged, true);
            app.AirdielectricEditField.Value = 1;
            app.WaterTempertatureCEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterTempertatureCEditFieldLabel.Text = 'Water Tempertature (°C)';
            app.WaterTempertatureCEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterTempertatureCEditField.ValueChangedFcn = createCallbackFcn(app, @WaterTempertatureCEditFieldValueChanged, true);
            app.WaterTempertatureCEditField.Value = 20;
            app.WaterSalinitypptEditFieldLabel = uilabel(app.EnvironmentPropertiesPanel);
            app.WaterSalinitypptEditFieldLabel.Text = 'Water Salinity (ppt)';
            app.WaterSalinitypptEditField = uieditfield(app.EnvironmentPropertiesPanel, 'numeric');
            app.WaterSalinitypptEditField.ValueChangedFcn = createCallbackFcn(app, @WaterSalinitypptEditFieldValueChanged, true);
            app.WaterSalinitypptEditField.Value = 35;
            app.NoisePropertiesPanel = uipanel(app.LeftPanel);
            app.NoisePropertiesPanel.Title = ' Noise Properties';
            app.VarianceEditFieldLabel = uilabel(app.NoisePropertiesPanel);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.NoisePropertiesPanel, 'numeric');
            app.VarianceEditField.ValueChangedFcn = createCallbackFcn(app, @VarianceEditFieldValueChanged, true);
            app.VarianceEditField.Value = 0.02;
            app.SamplesSliderLabel = uilabel(app.NoisePropertiesPanel);
            app.SamplesSliderLabel.Text = 'Samples';
            app.SamplesSlider = uislider(app.NoisePropertiesPanel);
            app.SamplesSlider.Limits = [1 5000];
            app.SamplesSlider.MajorTicks = [1 1000 2000 3000 4000 5000];
            app.SamplesSlider.ValueChangedFcn = createCallbackFcn(app, @SamplesSliderValueChanged, true);
            app.SamplesSlider.Value = 1000;
            app.DesiredthicknessEditFieldLabel = uilabel(app.NoisePropertiesPanel);
            app.DesiredthicknessEditFieldLabel.Text = 'Desired thickness';
            app.DesiredthicknessEditField = uieditfield(app.NoisePropertiesPanel, 'numeric');
            app.DesiredthicknessEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredthicknessEditFieldValueChanged, true);
            app.SpillButton = uibutton(app.LeftPanel, 'push');
            app.SpillButton.ButtonPushedFcn = createCallbackFcn(app, @SpillButtonPushed, true);
            app.SpillButton.Text = 'Spill';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AdditionalPlotsPanel = uipanel(app.LeftPanel);
            app.AdditionalPlotsPanel.Title = ' Additional Plots';
            app.OptionsDropDownLabel = uilabel(app.AdditionalPlotsPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.AdditionalPlotsPanel);
            app.OptionsDropDown.Items = {'Probability of detection', 'Thickness Estimation'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Probability of detection';
            app.GenerateforallfrequencytriadsButton = uibutton(app.AdditionalPlotsPanel, 'push');
            app.GenerateforallfrequencytriadsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateforallfrequencytriadsButtonPushed, true);
            app.GenerateforallfrequencytriadsButton.Text = 'Generate for all frequency triads';
            app.GenerateforallfrequencypairsButton = uibutton(app.AdditionalPlotsPanel, 'push');
            app.GenerateforallfrequencypairsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateforallfrequencypairsButtonPushed, true);
            app.GenerateforallfrequencypairsButton.Text = 'Generate for all frequency pairs';
            app.RightPanel = uipanel(app.GridLayout);
            app.ShowhistogramButton = uibutton(app.RightPanel, 'push');
            app.ShowhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowhistogramButtonPushed, true);
            app.ShowhistogramButton.Text = 'Show histogram';
            app.PlotsDropDownLabel = uilabel(app.RightPanel);
            app.PlotsDropDownLabel.Text = 'Plots';
            app.PlotsDropDown = uidropdown(app.RightPanel);
            app.PlotsDropDown.Items = {'Reflectivity vs. thickness', 'Probability of detection vs. thickness'};
            app.PlotsDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotsDropDownValueChanged, true);
            app.PlotsDropDown.Value = 'Reflectivity vs. thickness';
            app.ClearfigureButton = uibutton(app.RightPanel, 'push');
            app.ClearfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClearfigureButtonPushed, true);
            app.ClearfigureButton.Text = 'Clear figure';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ShowGridLinesCheckBox = uicheckbox(app.RightPanel);
            app.ShowGridLinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGridLinesCheckBoxValueChanged, true);
            app.ShowGridLinesCheckBox.Text = 'Show Grid Lines';
            app.ThicknessEstimatedLabel = uilabel(app.RightPanel);
            app.ThicknessEstimatedLabel.Text = 'Thickness Estimated';
            app.ThicknessEstimated = uieditfield(app.RightPanel, 'numeric');
            app.ThicknessEstimated.Editable = 'off';
            app.ProbabilityoferrorEditFieldLabel = uilabel(app.RightPanel);
            app.ProbabilityoferrorEditFieldLabel.Text = 'Probability of error';
            app.ProbabilityoferrorEditField = uieditfield(app.RightPanel, 'numeric');
            app.ProbabilityoferrorEditField.Editable = 'off';
            app.MaximumErrorEditFieldLabel = uilabel(app.RightPanel);
            app.MaximumErrorEditFieldLabel.Text = 'Maximum Error';
            app.MaximumErrorEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaximumErrorEditField.Editable = 'off';
            app.mmSpinnerLabel = uilabel(app.RightPanel);
            app.mmSpinnerLabel.Text = '+/- (mm)';
            app.mmSpinner = uispinner(app.RightPanel);
            app.mmSpinner.ValueChangedFcn = createCallbackFcn(app, @mmSpinnerValueChanged, true);
            app.Default = uicheckbox(app.RightPanel);
            app.Default.ValueChangedFcn = createCallbackFcn(app, @DefaultValueChanged, true);
            app.Default.Text = 'Default algorithm';
            app.Jointpdf = uicheckbox(app.RightPanel);
            app.Jointpdf.ValueChangedFcn = createCallbackFcn(app, @JointpdfValueChanged, true);
            app.Jointpdf.Text = {'Joint pdf'; ''};
            app.Jointpdf.Value = true;
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end